classdef FilterData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        main_figure                 matlab.ui.Figure
        grid_datainfo               matlab.ui.container.GridLayout
        label_FilterWinfowLength_4  matlab.ui.control.Label
        label_FilterWinfowLength_3  matlab.ui.control.Label
        label_FilterWinfowLength_2  matlab.ui.control.Label
        edit_DecimateFilter         matlab.ui.control.NumericEditField
        edit_MeanFilter             matlab.ui.control.NumericEditField
        edit_MedianFilter           matlab.ui.control.NumericEditField
        label_FilterWinfowLength    matlab.ui.control.Label
        edit_NumberofTherm          matlab.ui.control.EditField
        NumberofThermistorsLabel    matlab.ui.control.Label
        button_LoadRaw              matlab.ui.control.Button
        edit_pulse_W                matlab.ui.control.EditField
        PulseWLabel                 matlab.ui.control.Label
        edit_decay                  matlab.ui.control.EditField
        DecayminEditFieldLabel      matlab.ui.control.Label
        edit_pulse_s                matlab.ui.control.EditField
        PulsesecEditFieldLabel      matlab.ui.control.Label
        edit_logint                 matlab.ui.control.EditField
        LogIntervalsLabel           matlab.ui.control.Label
        edit_end_time               matlab.ui.control.EditField
        EndTimeEditFieldLabel       matlab.ui.control.Label
        edit_start_time             matlab.ui.control.EditField
        StartTimeEditFieldLabel     matlab.ui.control.Label
        edit_start_date             matlab.ui.control.EditField
        StartDateEditFieldLabel     matlab.ui.control.Label
        edit_filename               matlab.ui.control.EditField
        FilenameLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
        datauniquetimes
        Traw
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, H, datauniquetimes, Traw)
            app.CallingApp = mainapp;
            app.main_figure.WindowStyle = 'modal';
            movegui(app.main_figure, "center");

            % Define variables for GUI
            app.edit_NumberofTherm.Value=num2str(H.Fileinfo.No_Thermistors.Value);
            app.edit_pulse_W.Value=num2str(H.Fileinfo.Pulse_Power.Value);
            app.edit_decay.Value=num2str(H.Fileinfo.Decay_Time.Value);
            app.edit_pulse_s.Value=num2str(H.Fileinfo.Pulse_Length.Value);
            app.edit_logint.Value=num2str(H.Fileinfo.Log_Interval.Value);
            app.edit_end_time.Value=num2str(H.Fileinfo.End_Time.Value);
            app.edit_start_time.Value=num2str(H.Fileinfo.Start_Time.Value);
            app.edit_start_date.Value=num2str(H.Fileinfo.Start_Date.Value);

            app.edit_start_date.Editable='off';
            app.edit_start_time.Editable='off';
            app.edit_end_time.Editable='off';
            app.edit_logint.Editable='off';
            app.edit_pulse_s.Editable='off';
            app.edit_decay.Editable='off';
            app.edit_pulse_W.Editable='off';
            app.edit_NumberofTherm.Editable='off';

            app.datauniquetimes = datauniquetimes;
            app.Traw=Traw;
        end

        % Button pushed function: button_LoadRaw
        function button_LoadRawButtonPushed(app, event)
           [Tcln, ...
            decimateddata,...
            Record] ...
            = Filter(app.main_figure, ...
            app.edit_MedianFilter,...
            app.edit_MeanFilter,...
            app.edit_DecimateFilter,...
            app.datauniquetimes, ...
            app.Traw, ...
            app.edit_NumberofTherm.Value);

            % Send user-defined variables from GUI back to main app
            filterData(app.CallingApp, Tcln, ...
            decimateddata,...
            Record)
        
        % Delete app
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.main_figure = uifigure('Visible', 'off');
            app.main_figure.Name = 'MATLAB App';
            app.grid_datainfo = uigridlayout(app.main_figure);
            app.FilenameLabel = uilabel(app.grid_datainfo);
            app.FilenameLabel.Text = 'Raw data file:';
            app.edit_filename = uieditfield(app.grid_datainfo, 'text');
            app.edit_filename.Enable = 'off';
            app.edit_filename.Tooltip = {'Filename for .raw data file'};
            app.StartDateEditFieldLabel = uilabel(app.grid_datainfo);
            app.StartDateEditFieldLabel.Text = 'Start Date:';
            app.edit_start_date = uieditfield(app.grid_datainfo, 'text');
            app.edit_start_date.Enable = 'off';
            app.edit_start_date.Tooltip = {'Date of start of instrument deployment'};
            app.StartTimeEditFieldLabel = uilabel(app.grid_datainfo);
            app.StartTimeEditFieldLabel.Text = 'Start Time:';
            app.edit_start_time = uieditfield(app.grid_datainfo, 'text');
            app.edit_start_time.Enable = 'off';
            app.edit_start_time.Tooltip = {'Time of start of instrument deployment'};
            app.EndTimeEditFieldLabel = uilabel(app.grid_datainfo);
            app.EndTimeEditFieldLabel.Text = 'End Time:';
            app.edit_end_time = uieditfield(app.grid_datainfo, 'text');
            app.edit_end_time.Enable = 'off';
            app.edit_end_time.Tooltip = {'Time of end of instrument deployment'};
            app.LogIntervalsLabel = uilabel(app.grid_datainfo);
            app.LogIntervalsLabel.Text = 'Log Interval (s):';
            app.edit_logint = uieditfield(app.grid_datainfo, 'text');
            app.edit_logint.Enable = 'off';
            app.PulsesecEditFieldLabel = uilabel(app.grid_datainfo);
            app.PulsesecEditFieldLabel.Text = 'Pulse (sec): ';
            app.edit_pulse_s = uieditfield(app.grid_datainfo, 'text');
            app.edit_pulse_s.Enable = 'off';
            app.edit_pulse_s.Tooltip = {'Duration of heat pulse'};
            app.DecayminEditFieldLabel = uilabel(app.grid_datainfo);
            app.DecayminEditFieldLabel.Text = 'Decay (min):';
            app.edit_decay = uieditfield(app.grid_datainfo, 'text');
            app.edit_decay.Enable = 'off';
            app.edit_decay.Tooltip = {'Duration of heat pulse decay'};
            app.PulseWLabel = uilabel(app.grid_datainfo);
            app.PulseWLabel.Text = 'Pulse Power (W): ';
            app.edit_pulse_W = uieditfield(app.grid_datainfo, 'text');
            app.edit_pulse_W.Enable = 'off';
            app.edit_pulse_W.Tooltip = {'Power of heat pulse'};
            app.button_LoadRaw = uibutton(app.grid_datainfo, 'push');
            app.button_LoadRaw.ButtonPushedFcn = createCallbackFcn(app, @button_LoadRawButtonPushed, true);
            app.button_LoadRaw.Tooltip = {'Load raw data from a .raw text file'};
            app.button_LoadRaw.Text = 'Filter Raw Data';
            app.NumberofThermistorsLabel = uilabel(app.grid_datainfo);
            app.NumberofThermistorsLabel.Text = 'Number of Thermistors:';
            app.edit_NumberofTherm = uieditfield(app.grid_datainfo, 'text');
            app.edit_NumberofTherm.Enable = 'off';
            app.edit_NumberofTherm.Tooltip = {'Number of thermistors used'};
            app.label_FilterWinfowLength = uilabel(app.grid_datainfo);
            app.label_FilterWinfowLength.Enable = 'off';
            app.label_FilterWinfowLength.Text = 'Filter Window Lengths:';
            app.edit_MedianFilter = uieditfield(app.grid_datainfo, 'numeric');
            app.edit_MedianFilter.Value = 2;
            app.edit_MeanFilter = uieditfield(app.grid_datainfo, 'numeric');
            app.edit_MeanFilter.Value = 6;
            app.edit_DecimateFilter = uieditfield(app.grid_datainfo, 'numeric');
            app.edit_DecimateFilter.Value = 10;
            app.label_FilterWinfowLength_2 = uilabel(app.grid_datainfo);
            app.label_FilterWinfowLength_2.Enable = 'off';
            app.label_FilterWinfowLength_2.Text = 'Median';
            app.label_FilterWinfowLength_3 = uilabel(app.grid_datainfo);
            app.label_FilterWinfowLength_3.Enable = 'off';
            app.label_FilterWinfowLength_3.Text = 'Mean';
            app.label_FilterWinfowLength_4 = uilabel(app.grid_datainfo);
            app.label_FilterWinfowLength_4.Enable = 'off';
            app.label_FilterWinfowLength_4.Text = 'Window Length';
            % Show the figure after all components are created
            app.main_figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilterData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.main_figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.main_figure)
        end
    end
end