classdef SetPenInfo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_SetPenInfo               matlab.ui.Figure
        panel_main                      matlab.ui.container.Panel
        excludingtopsensorLabel         matlab.ui.control.Label
        edit_numACTIVEtherm             matlab.ui.control.EditField
        NumberofActiveThermistorsLabel  matlab.ui.control.Label
        CreatepenfileButton             matlab.ui.control.Button
        edit_station                    matlab.ui.control.EditField
        StationEditFieldLabel           matlab.ui.control.Label
        edit_cruise                     matlab.ui.control.EditField
        CruiseEditField_12Label         matlab.ui.control.Label
        edit_depth                      matlab.ui.control.EditField
        DepthmbsfLabel                  matlab.ui.control.Label
        edit_tilt                       matlab.ui.control.EditField
        TiltLabel                       matlab.ui.control.Label
        edit_longitude                  matlab.ui.control.EditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        edit_latitude                   matlab.ui.control.EditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        edit_numtherm                   matlab.ui.control.EditField
        NumberofThermistorstotalLabel   matlab.ui.control.Label
        edit_instrument                 matlab.ui.control.EditField
        InstrumentEditFieldLabel        matlab.ui.control.Label
        edit_datum                      matlab.ui.control.EditField
        DatumEditFieldLabel             matlab.ui.control.Label
        edit_sensor                     matlab.ui.control.EditField
        SensorEditFieldLabel            matlab.ui.control.Label
        edit_penetration                matlab.ui.control.EditField
        PenetrationEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, NoTherm, NumSensUsed, SensorsToUse, PenTilt, PenDepth, Cruisename, Stationname)
         % Initialize GUI   
            app.CallingApp = mainapp;
            app.figure_SetPenInfo.WindowStyle = 'modal';
            movegui(app.figure_SetPenInfo, "center");
        % Define variables for GUI
            app.edit_tilt.Value = num2str(PenTilt);
            app.edit_depth.Value = num2str(PenDepth);
            app.edit_numtherm.Value = num2str(NoTherm);
            app.edit_numACTIVEtherm.Value = num2str(NumSensUsed);
            app.edit_cruise.Value = Cruisename;
            app.edit_station.Value = Stationname;

        end

        % Button pushed function: CreatepenfileButton
        function CreatepenfileButtonPushed(app, event)

        % Send user-defined variables from GUI back to main app
            setPenInfo(app.CallingApp, app.edit_cruise.Value, app.edit_station.Value, ...
                app.edit_penetration.Value, app.edit_sensor.Value, app.edit_datum.Value, ...
                app.edit_instrument.Value, app.edit_numtherm.Value, app.edit_numACTIVEtherm.Value, ...
                app.edit_latitude.Value, app.edit_longitude.Value, app.edit_tilt.Value, app.edit_depth.Value, app.figure_SetPenInfo)
        
        % Delete app
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure_SetPenInfo = uifigure('Visible', 'off');
            app.figure_SetPenInfo.Name = 'MATLAB App';
            app.panel_main = uipanel(app.figure_SetPenInfo);
            app.panel_main.Title = 'Set Penetration Information';
            app.PenetrationEditFieldLabel = uilabel(app.panel_main);
            app.PenetrationEditFieldLabel.Text = 'Penetration:';
            app.edit_penetration = uieditfield(app.panel_main, 'text');
            app.SensorEditFieldLabel = uilabel(app.panel_main);
            app.SensorEditFieldLabel.Text = 'Sensor:';
            app.edit_sensor = uieditfield(app.panel_main, 'text');
            app.edit_sensor.Value = '-999';
            app.DatumEditFieldLabel = uilabel(app.panel_main);
            app.DatumEditFieldLabel.Text = 'Datum:';
            app.edit_datum = uieditfield(app.panel_main, 'text');
            app.edit_datum.Value = '-999';
            app.InstrumentEditFieldLabel = uilabel(app.panel_main);
            app.InstrumentEditFieldLabel.Text = 'Instrument:';
            app.edit_instrument = uieditfield(app.panel_main, 'text');
            app.edit_instrument.Value = '-999';
            app.NumberofThermistorstotalLabel = uilabel(app.panel_main);
            app.NumberofThermistorstotalLabel.Text = 'Total number of sensors  (including top sensor):';
            app.edit_numtherm = uieditfield(app.panel_main, 'text');
            app.LatitudeEditFieldLabel = uilabel(app.panel_main);
            app.LatitudeEditFieldLabel.Text = 'Latitude:';
            app.edit_latitude = uieditfield(app.panel_main, 'text');
            app.edit_latitude.Value = '-999';
            app.LongitudeEditFieldLabel = uilabel(app.panel_main);
            app.LongitudeEditFieldLabel.Text = 'Longitude:';
            app.edit_longitude = uieditfield(app.panel_main, 'text');
            app.edit_longitude.Value = '-999';
            app.TiltLabel = uilabel(app.panel_main);
            app.TiltLabel.Text = 'Tilt (°):';
            app.edit_tilt = uieditfield(app.panel_main, 'text');
            app.DepthmbsfLabel = uilabel(app.panel_main);
            app.DepthmbsfLabel.Text = 'Depth (m):';
            app.edit_depth = uieditfield(app.panel_main, 'text');
            app.CruiseEditField_12Label = uilabel(app.panel_main);
            app.CruiseEditField_12Label.Text = 'Cruise:';
            app.edit_cruise = uieditfield(app.panel_main, 'text');
            app.StationEditFieldLabel = uilabel(app.panel_main);
            app.StationEditFieldLabel.Text = 'Station:';
            app.edit_station = uieditfield(app.panel_main, 'text');
            app.CreatepenfileButton = uibutton(app.panel_main, 'push');
            app.CreatepenfileButton.ButtonPushedFcn = createCallbackFcn(app, @CreatepenfileButtonPushed, true);
            app.CreatepenfileButton.Text = 'Create .pen file';
            app.NumberofActiveThermistorsLabel = uilabel(app.panel_main);
            app.NumberofActiveThermistorsLabel.Text = 'Number of active sensors only ';
            app.edit_numACTIVEtherm = uieditfield(app.panel_main, 'text');
            app.excludingtopsensorLabel = uilabel(app.panel_main);
            app.excludingtopsensorLabel.Text = '(excluding top sensor):';
            % Show the figure after all components are created
            app.figure_SetPenInfo.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetPenInfo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure_SetPenInfo)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_SetPenInfo)
        end
    end
end