classdef SlugPen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_Main                    matlab.ui.Figure
        grid_MainFigure                matlab.ui.container.GridLayout
        button_CommandWindowArrow      matlab.ui.control.Button
        panel_PlotWindow               matlab.ui.container.Panel
        grid_PlotWindow                matlab.ui.container.GridLayout
        button_RemoveSensors           matlab.ui.control.Button
        panel_Thermistors              matlab.ui.container.Panel
        panel_ProcessingInfoLabels     matlab.ui.container.Panel
        grid_ProcessingInfoLabels      matlab.ui.container.GridLayout
        label_ProcessingTime           matlab.ui.control.Label
        label_Longitude                matlab.ui.control.Label
        label_Latitude                 matlab.ui.control.Label
        label_Penetration              matlab.ui.control.Label
        label_Station                  matlab.ui.control.Label
        label_Cruise                   matlab.ui.control.Label
        edit_CruiseTitle               matlab.ui.control.Label
        edit_ProcessingTimeTitle       matlab.ui.control.Label
        edit_LongitudeTitle            matlab.ui.control.Label
        edit_LatitudeTitle             matlab.ui.control.Label
        edit_PenetrationTitle          matlab.ui.control.Label
        edit_StationTitle              matlab.ui.control.Label
        panel_Top                      matlab.ui.container.Panel
        panel_TopLabels                matlab.ui.container.Panel
        grid_TopLabels                 matlab.ui.container.GridLayout
        label_currentpathfull          matlab.ui.control.Label
        label_CurrentPath              matlab.ui.control.Label
        label_SlugHeat                 matlab.ui.control.Label
        label_Time                     matlab.ui.control.Label
        axis_tilt                      matlab.ui.control.UIAxes
        axis_accel                     matlab.ui.control.UIAxes
        axis_depth                     matlab.ui.control.UIAxes
        axis_raw                       matlab.ui.control.UIAxes
        panel_Data                     matlab.ui.container.Panel
        grid_data                      matlab.ui.container.GridLayout
        panel_info                     matlab.ui.container.Panel
        grid_datainfo                  matlab.ui.container.GridLayout
        edit_DecimateFilter            matlab.ui.control.NumericEditField
        DecimateEditFieldLabel         matlab.ui.control.Label
        edit_MeanFilter                matlab.ui.control.NumericEditField
        MeanEditFieldLabel             matlab.ui.control.Label
        edit_MedianFilter              matlab.ui.control.NumericEditField
        MedianEditFieldLabel           matlab.ui.control.Label
        label_FilterWinfowLength       matlab.ui.control.Label
        edit_NumberofTherm             matlab.ui.control.EditField
        NumberofThermistorsLabel       matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        edit_load                      matlab.ui.control.Button
        edit_pulse_W                   matlab.ui.control.EditField
        PulseWLabel                    matlab.ui.control.Label
        edit_decay                     matlab.ui.control.EditField
        DecayminEditFieldLabel         matlab.ui.control.Label
        edit_pulse_s                   matlab.ui.control.EditField
        PulsesecEditFieldLabel         matlab.ui.control.Label
        edit_logint                    matlab.ui.control.EditField
        LogIntervalsLabel              matlab.ui.control.Label
        edit_end_time                  matlab.ui.control.EditField
        EndTimeEditFieldLabel          matlab.ui.control.Label
        edit_start_time                matlab.ui.control.EditField
        StartTimeEditFieldLabel        matlab.ui.control.Label
        edit_start_date                matlab.ui.control.EditField
        StartDateEditFieldLabel        matlab.ui.control.Label
        edit_filename                  matlab.ui.control.EditField
        FilenameLabel                  matlab.ui.control.Label
        panel_selected                 matlab.ui.container.Panel
        grid_selected                  matlab.ui.container.GridLayout
        edit_HeatPulse                 matlab.ui.control.EditField
        HeatPulseHEditFieldLabel       matlab.ui.control.Label
        edit_End                       matlab.ui.control.EditField
        EndPenetrationEEditFieldLabel  matlab.ui.control.Label
        edit_PenStart                  matlab.ui.control.EditField
        StartPenetrationPEditFieldLabel  matlab.ui.control.Label
        edit_EndEqm                    matlab.ui.control.EditField
        EndCalibrationPeriodW2Label    matlab.ui.control.Label
        edit__StartEqm                 matlab.ui.control.EditField
        StartCalibrationPeriodW1Label  matlab.ui.control.Label
        checkbox_UseHP                 matlab.ui.control.CheckBox
        button__ResetPen               matlab.ui.control.Button
        button_SetPen                  matlab.ui.control.Button
        button_BeginSelections         matlab.ui.control.Button
        button_ResetSelection          matlab.ui.control.Button
        dropdown_Select                matlab.ui.control.DropDown
        panel_plotcontrols             matlab.ui.container.Panel
        grid_plotcontrols              matlab.ui.container.GridLayout
        checkbox_decp                  matlab.ui.control.CheckBox
        checkbox_tiltplot              matlab.ui.control.CheckBox
        checkbox_clnp                  matlab.ui.control.CheckBox
        checkbox_accelplot             matlab.ui.control.CheckBox
        checkbox_rawp                  matlab.ui.control.CheckBox
        checkbox_depthplot             matlab.ui.control.CheckBox
        label_datacheckboxes           matlab.ui.control.Label
        label_plotscheckboxes          matlab.ui.control.Label
        panel_SlugPen                  matlab.ui.container.Panel
        grid_SlugPen                   matlab.ui.container.GridLayout
        image_UCSC                     matlab.ui.control.Image
        image_SlugPen                  matlab.ui.control.Image
        button_restart                 matlab.ui.control.Button
        button_quit                    matlab.ui.control.Button
        panel_create                   matlab.ui.container.Panel
        grid_create                    matlab.ui.container.GridLayout
        export_pen                     matlab.ui.control.Button
    end


    properties (Access = public)
        Version = '1.0'  % Version of code
        Update = '04.06.2023' % date of last update by KD
        
        
        % Define global handles, aka PROPERTIES in case of App Designer %
        %filterwindows

        % Number of thermistors
        NoTherm % Total
        NoActiveTherm % ACTIVE, excluding bottom water
       
        
        % Properties for vertical line selections %
        VerticalLine
        roi
        x
        selTime
        selTime_Vector
        SE_VerticalLine
        EE_VerticalLine
        SP_VerticalLine
        HP_VerticalLine
        EP_VerticalLine
        SE_depthLine
        EE_depthLine
        SP_depthLine
        HP_depthLine
        EP_depthLine
        SE_accelLine
        EE_accelLine
        SP_accelLine
        HP_accelLine
        EP_accelLine
        SE_tiltLine
        EE_tiltLine
        SP_tiltLine
        HP_tiltLine
        EP_tiltLine
        
        % Properties for Initializing %

        % Previously hidden handles. Initial conditions all set to NaN in the start up function % 
        All_Zeros
        All_NaN
        H_Selected_raw 
        H_Selected_depth
        H_Selected_accel
        H_Selected_tilt
        H_Selected_raw_text
        H_Selected_depth_text
        H_Selected_accel_text
        H_Selected_tilt_text
        H_LegendSelect
        H_AxLims_Raw
        H_AxLims_Tilt
        H_AxLims_Depth
        H_AxLims_Acc
        H_NoThermistors

        % Flags %        
        ErrorFlag
        CropFlag

        % Preveiously set to {'default'} - I think should swtich to
        % {'test'} for app designer (but check)
        Params 
        ParamsVals
        Parameters
       
  
        % Inital structures %
        S_Axes = struct()
        S_AxLims = struct()
        S_Exe_Controls = struct()
        S_Selections = struct()
        S_Plot_Controls = struct()
        S_Selections_Lines = struct()
        S_VerticalLines = struct()
        S_FileInfo = struct()
        H = struct()
        DATA = struct() 

        SlugPen_CreatePenFileDialogBox % Dialog Box app for creating penfile

        % Penetration data variables
        Cruisename       
        StationName      
        Penetration      
        ProbeID         
        Datum           
        LoggerID         
        NumberOfSensors  
        Latitude          
        Longitude         
        Tilt_mean        
        Depth_mean  

         % Auxillary Apps
         % --------------
         SetPenInfoApp
    end
    
    properties (Access = private)
        % Data selections for raw, clean, and decimated with ROI
        rawSelect
        clnSelect
        decSelect
        rawData
        clnData
        decData
        roiSelect 

        % Plot checkboxes
        DepthPlot
        AccelPlot
        TiltPlot

        % User input info for penetration file header
        penetrationInfo % Description
        
        % Axes children
        C 

        % Variable to enable the create penfile button %
        PenSet 

        Checkboxes % Description
        PulsePower % vector with power in Joules of each heat pulse fire
        PulseData % 0 if there is no heat pulse. 1 if there is a heat pulse 
        HeatPulsePens % array that has a 1 for all penetrations with a heat pulse and a 0 for all penetrations without a heat pulse
        NumSensUsed % number of sensors used after removal, does NOT include bottom water
    end

    methods (Access = private)

        function xAlign(app)
            % Align inner position left edge and width (x axis) of all 4 plots
            app.axis_raw.YLabel.Units = 'normalized';
            app.axis_depth.YLabel.Units = 'normalized';
            app.axis_accel.YLabel.Units = 'normalized';
            app.axis_tilt.YLabel.Units = 'normalized';
            pause(1);
            app.axis_accel.InnerPosition(1) = app.axis_raw.InnerPosition(1);
            app.axis_accel.InnerPosition(3) = app.axis_raw.InnerPosition(3);
            app.axis_depth.InnerPosition(1) = app.axis_raw.InnerPosition(1);
            app.axis_depth.InnerPosition(3) = app.axis_raw.InnerPosition(3);
            app.axis_tilt.InnerPosition(1) = app.axis_raw.InnerPosition(1);
            app.axis_tilt.InnerPosition(3) = app.axis_raw.InnerPosition(3);
            pause(1);
            app.axis_raw.PositionConstraint='innerposition';
            app.axis_depth.PositionConstraint='innerposition';
            app.axis_accel.PositionConstraint='innerposition';
            app.axis_tilt.PositionConstraint='innerposition';
            pause(1);
            drawnow;
            pause(0.5)
            
        end
        
        function SelectVerticalLines(app)
        zoom(app.axis_raw, 'off')
        pan(app.axis_raw, 'off')
        enableDefaultInteractivity(app.axis_raw);
        %app.axis_raw.Interactions = [];
        app.x = app.dropdown_Select.Value;
        app.roi = drawpoint(app.axis_raw); % Must have image processing toolbox, only works with UIAxes in 2019 or later
        %app.roi = ginput(1); --> Only for matlab version 2018b or earlier
        app.selTime = app.roi.Position(1);
        depthLine = xline(app.axis_depth, app.selTime, '-.k', 'LineWidth', 2);
        tiltLine = xline(app.axis_tilt, app.selTime, '-.k', 'LineWidth', 2);
        accelLine = xline(app.axis_accel, app.selTime, '-.k', 'LineWidth', 2);
        app.VerticalLine = xline(app.axis_raw, app.selTime, '-.k', app.x, 'LineWidth', 2, "LabelHorizontalAlignment","center","FontSize",24, "FontWeight","bold");
        %app.VerticalLine.Label = app.x;
        app.selTime = datestr(app.selTime, 'mm/dd/yyyy HH:MM:SS');
        delete(app.roi)
        app.VerticalLine.Label = app.x;
        app.VerticalLine.LabelHorizontalAlignment = 'center';
        drawnow;
        switch app.x
            case 'Start Calibration Period (C1)'
                app.H.Selections.Start_Eqm.Value = num2str(app.selTime);
                delete(app.SE_VerticalLine)
                app.SE_VerticalLine = app.VerticalLine;
                delete(app.SE_depthLine)
                app.SE_depthLine = depthLine;
                delete(app.SE_accelLine)
                app.SE_accelLine = accelLine;
                delete(app.SE_tiltLine)
                app.SE_tiltLine = tiltLine;
            case 'End Calibration Period (C2)'
                app.H.Selections.End_Eqm.Value = num2str(app.selTime);
                delete(app.EE_VerticalLine)
                app.EE_VerticalLine = app.VerticalLine;
                delete(app.EE_depthLine)
                app.EE_depthLine = depthLine;
                delete(app.EE_accelLine)
                app.EE_accelLine = accelLine;
                delete(app.EE_tiltLine)
                app.EE_tiltLine = tiltLine;
            case 'Start Penetration (P)'
                app.H.Selections.Start_Pen.Value = num2str(app.selTime);
                delete(app.SP_VerticalLine)
                app.SP_VerticalLine = app.VerticalLine;
                delete(app.SP_depthLine)
                app.SP_depthLine = depthLine;
                delete(app.SP_accelLine)
                app.SP_accelLine = accelLine;
                delete(app.SP_tiltLine)
                app.SP_tiltLine = tiltLine;
            case 'Heat Pulse (H)'
                app.H.Selections.Start_Heat.Value = num2str(app.selTime);
                delete(app.HP_VerticalLine)
                app.HP_VerticalLine = app.VerticalLine;
                delete(app.HP_depthLine)
                app.HP_depthLine = depthLine;
                delete(app.HP_accelLine)
                app.HP_accelLine = accelLine;
                delete(app.HP_tiltLine)
                app.HP_tiltLine = tiltLine;
            case 'End Penetration (E)'
                app.H.Selections.End_Pen.Value = num2str(app.selTime);
                delete(app.EP_VerticalLine)
                app.EP_VerticalLine = app.VerticalLine;
                delete(app.EP_depthLine)
                app.EP_depthLine = depthLine;
                delete(app.EP_accelLine)
                app.EP_accelLine = accelLine;
                delete(app.EP_tiltLine)
                app.EP_tiltLine = tiltLine;
        end
        zoom(app.axis_raw, 'on')
        pan(app.axis_raw, 'on')

        end
        
        function SelectROI(app)
            app.NoTherm = app.H.Fileinfo.No_Thermistors.Value;
            % Turn off zoom and pan
            pan(app.axis_raw, "off");
            zoom(app.axis_raw, "off");
            
            % Extract Time
            Time     = app.DATA.Time;       % For Raw and Cln
            Time_dec = app.DATA.Time_dec;   % For Decimated
            % Extract temp data
            Traw = app.DATA.Traw;
            Tcln = app.DATA.Tcln;
            Tdec = app.DATA.Tdec;

            % Combine time and temp data
            rawData = [Time;Traw];
            clnData = [Time; Tcln];
            app.decData = [Time_dec; Tdec];
            disableDefaultInteractivity(app.axis_raw)
            app.roiSelect = drawfreehand(app.axis_raw);
            app.roiSelect.InteractionsAllowed = 'none';
            drawnow
            % Selected xdata and ydata %
            i=1;
            %rawselected = inROI(app.roiSelect, Time, Traw(1,:))';
            rawSelected=zeros(5174, app.NoTherm);
            clnSelected=zeros(5174, app.NoTherm);
            decSelected=zeros(518, app.NoTherm);
            %rawSelected = inROI(app.roiSelect, Time, Traw(1,:));
            while i<=app.NoTherm
                rawSelected(:,i) = inROI(app.roiSelect, Time, Traw(i,:));
                i=i+1;
            end
            
            rawSelected=rawSelected(i,:);

        end
        
        function PlotData(app)
           loading = uiprogressdlg(app.figure_Main, 'Message', 'Plotting raw data...');
           loading.Value = .6;

            %% CLEAR AXES
            cla(app.H.Axes.Raw,'reset');
            cla(app.H.Axes.Depth,'reset');
            cla(app.H.Axes.Tilt,'reset');
            cla(app.H.Axes.Accel,'reset');

            % Determine Number of Thermistors
            app.NoTherm = app.H.Fileinfo.No_Thermistors.Value;

            % Extract Decimated Data for plotting
            T_dec          = app.DATA.Tdec;
            Depth_dec      = app.DATA.Depth_dec;
            G_dec          = app.DATA.G_dec;
            Tilt_dec       = app.DATA.Tilt_dec;
            Time_dec       = app.DATA.Time_dec;
            record_dec     = app.DATA.Record_dec;

            % Extract Clean Data for plotting
            T_cln          = app.DATA.Tcln;

            % Extract Raw Data for plotting
            T      = app.DATA.Traw;
            Depth  = app.DATA.Depth;
            G      = app.DATA.G;
            Tilt   = app.DATA.Tilt;
            Time   = app.DATA.Time;
            record = app.DATA.Record;

            % Enable buttons and fill in text
            %app.H.Exe_Controls.Export_Ascii.Enable='on';
            app.H.Selections.Select.Enable='on';
            app.H.Exe_Controls.Delete.Enable='on';

            %% COLORMAP
            colormap(app.axis_raw, 'default');
            CMap= colormap(app.axis_raw, 'turbo');
            CMap = flipud(CMap);
            CMap = interp1(1:256,CMap,1:256/(app.NoTherm-1):256); % 'jet' colormap for Matlab version 2019a and later has size 256x3 RBG matrix
            %CMap = interp1([1:64],CMap,[1:64/(app.NoTherm-1):64]); %'jet' colormap for Matlab version 2018b or earlier has size 64x3 RBG matrix
            colormap(app.axis_raw, CMap);


            %% Plot DECIMATED (median filtered) TEMPERATURE/OHM DATA AS o's

            % Vector to store plot handles for each thermistor 
            %h_axDec=zeros(1,app.NoTherm);
            leg = zeros(1,app.NoTherm);
            i=1;
            while i<=app.NoTherm-1
                % Plot by time
                h_axDec(i) = plot(app.axis_raw, Time_dec,T_dec(i,:),'-o','markersize',2,...
                    'color',CMap(i,:),'markerfacecolor',CMap(i,:));    
                hold(app.axis_raw, 'on');
                leg(i) = i;
                i=i+1;
            end

            % Plot DECIMATED Bottom Water
            h_axDec(i) = plot(app.axis_raw, Time_dec,T_dec(app.NoTherm,:),'k-x','markersize',2);


            % Save plot handles
            app.H.Plot_Controls.DecimatedPlot.UserData = h_axDec;

            % Enable Plot Control Toggle
            app.H.Plot_Controls.DecimatedPlot.Enable='on';
            
            % Define decimated plot
            app.H.Plot_Controls.DecimatedPlot.Value = app.checkbox_decp.Value; 

            % Raw Axis Limits
            V_T = axis(app.axis_raw);

            % Labels, Date ticks, and Title
            if T_dec(5,10)>100
                ylabel(app.axis_raw, 'Resistance (Ohm)');
            else
            ylabel(app.axis_raw, 'Temperature (^oC)', 'FontWeight', 'bold');
            end

            
            % Keep Visible or Hide
            if app.H.Plot_Controls.DecimatedPlot.Value == 0
                for i=1:app.NoTherm
                  set(app.H.Plot_Controls.DecimatedPlot.UserData(i),'Visible','off');
                end
            end


            %% Plot CLEAN Data
            % Plot CLEAN (median filtered) data as x's
            % Vector to store plot handles for each thermistor 
            %h_axCln=zeros(1,app.NoTherm);
            i=1;
            while i<=app.NoTherm-1
                h_axCln(i) = plot(app.axis_raw, Time,T_cln(i,:),'o','markersize',2,...
                    'color',CMap(i,:),'markerfacecolor',CMap(i,:));
                hold(app.axis_raw, "on");
                i=i+1;
            end
            
            % Plot CLEAN Bottom Water
            h_axCln(i) = plot(app.axis_raw, Time,T_cln(app.NoTherm,:),'kv','markersize',2,'markerfacecolor','b');

            % Turn Bottom Water off;
            set(h_axCln(i),'Visible','off')


            % Save plot handles
            app.H.Plot_Controls.CleanPlot.UserData = h_axCln;

            % Enable Plot Control Toggle
            app.H.Plot_Controls.CleanPlot.Enable='on';

            % Keep Visible or Hide
            if app.H.Plot_Controls.CleanPlot.Value == 0
                for i=1:app.NoTherm
                    set(app.H.Plot_Controls.CleanPlot.UserData(i),'Visible','off');
                end
    
            end

            % Hide clear data
            app.checkbox_clnp.Value = 0;
%
            %% RAW DATA PLOT

            % Plot RAW data as x's
            i=1;
            while i<=app.NoTherm-1
                h_axRaw(i) = plot(app.axis_raw, Time,T(i,:),'x','markersize',2,'color',CMap(i,:), 'Visible', 'off');
                hold(app.axis_raw, "on");
                i=i+1;
            end
            % Plot RAW Bottom Water
            h_axRaw(i) = plot(app.axis_raw, Time,T(app.NoTherm,:),'kv','markersize',2);

            % Turn Bottom Water off;
            set(h_axRaw(i),'Visible','off')

            % Save plot handles
            app.H.Plot_Controls.RawPlot.UserData = h_axRaw;

            % Enable Plot Control Toggle
            app.H.Plot_Controls.RawPlot.Enable='on';
            app.H.Plot_Controls.RawPlot.Value=1;

            app.axis_raw.PositionConstraint = 'innerposition'; % constrains axes box to inner position

            % Hide Raw data
            app.checkbox_rawp.Value = 0;

            %% DEPTH - DECIMATED
            % Plot depth data 
            h_axDep=plot(app.axis_depth, Time_dec,-Depth_dec,'b');
            ylabel(app.axis_depth, 'Depth (m)', 'FontWeight','bold');

            % Depth Axis Limits
            V_Zd = axis(app.axis_depth);

            % Add the axes data to the Structure
            app.H.Axes.Depth.UserData = h_axDep;

            app.axis_depth.PositionConstraint = 'innerposition'; % constrains axes box to inner position

            %% ACCELERATION - DECIMATED
            % Plot Acceleration data 
            h_axAcc=plot(app.axis_accel, Time_dec,G_dec,'k');
            ylabel(app.axis_accel, 'Acceleration (g)', 'FontWeight','bold');

            % Acceleration Axis Limits
            V_Za = axis(app.axis_accel);

            % Add the axes data to the Structure
            app.H.Axes.Accel.UserData = h_axAcc;

            app.axis_accel.PositionConstraint = 'innerposition'; % constrains axes box to inner position


            %% TILT PLOT - DECIMATED
            % Plot Tilt
            plot(app.axis_tilt, Time_dec,Tilt_dec,'r'); hold(app.axis_tilt, "on")
            ylabel(app.axis_tilt, 'Tilt (°)', 'FontWeight','bold');

            % Tilt Axis Limits
            V_Tilt = axis(app.axis_tilt);

            app.axis_tilt.PositionConstraint = 'innerposition'; % constrains axes box to inner position

            %% STORE ORIGINAL AXES LIMITS
            app.H.Axis_Limits.AxLims_Raw.Value=V_T;
            app.H.Axis_Limits.AxLims_Depth.Value=V_Zd;
            app.H.Axis_Limits.AxLims_Acc.Value=V_Za;
            app.H.Axis_Limits.AxLims_Tilt.Value=V_Tilt;

            %% Link Axes and Assign Dynamic Date Ticks
            tempAx = app.H.Axes.Raw;
            accelAx = app.H.Axes.Accel;
            depthAx = app.H.Axes.Depth;
            tiltAx = app.H.Axes.Tilt;
            ax=[tempAx depthAx accelAx tiltAx];
            linkaxes(ax,'x');
            mdformat = 'HH:MM:SS'; % variable for month/day format 

            % Apply datetick to all axes in axH, and store any linking information
            % temperature axis
            axH = tempAx;
            axesInfo.Type = 'dateaxes'; % Information stored in axes userdata indicating that these are date axes
            for i = 1:length(axH)
                datetick(axH(i), 'x');
                axesInfo.Linked = axH;
                axesInfo.mdformat = mdformat; % Remember mm/dd format for each axes
                set(axH(i), 'UserData', axesInfo); % Store the fact that this is a date axes and its link & mm/dd information in userdata
            end

            % acceleration axis
            axH = accelAx;
            axesInfo.Type = 'dateaxes'; % Information stored in axes userdata indicating that these are date axes
            for i = 1:length(axH)
                datetick(axH(i), 'x');
                axesInfo.Linked = axH;
                axesInfo.mdformat = mdformat; % Remember mm/dd format for each axes
                set(axH(i), 'UserData', axesInfo); % Store the fact that this is a date axes and its link & mm/dd information in userdata
            end

            % depth axis
            axH = depthAx;
            axesInfo.Type = 'dateaxes'; % Information stored in axes userdata indicating that these are date axes
            for i = 1:length(axH)
                datetick(axH(i), 'x');
                axesInfo.Linked = axH;
                axesInfo.mdformat = mdformat; % Remember mm/dd format for each axes
                set(axH(i), 'UserData', axesInfo); % Store the fact that this is a date axes and its link & mm/dd information in userdata
            end
            axH.YTickLabelRotation = 15;

            % tilt axis
            axH = tiltAx;
            axesInfo.Type = 'dateaxes'; % Information stored in axes userdata indicating that these are date axes
            for i = 1:length(axH)
                datetick(axH(i), 'x');
                axesInfo.Linked = axH;
                axesInfo.mdformat = mdformat; % Remember mm/dd format for each axes
                set(axH(i), 'UserData', axesInfo); % Store the fact that this is a date axes and its link & mm/dd information in userdata
            end
        loading.Message = 'Finishing...';
        loading.Value = .9;

            % Align inner position left edge and width (x axis) of all 4 plots
            app.xAlign

            % Plot sensor checkboxes
            % ----------------------
            app.NumberOfSensors = app.NoTherm;
            
                % Grid layout for sensor panel
                % -----------------------------
                    
                    for i=app.NumberOfSensors:-1:1
                        ThermistorGrid = uigridlayout(app.panel_Thermistors, 'BackgroundColor', ...
                        [0.94 0.94 0.94], 'ColumnWidth', {'100x'}, 'RowHeight', cellstr(repmat('1x', app.NumberOfSensors, 1))');
                    end
            
            
                % Create panels for each sensor
                % ------------------------------
                    for i=app.NumberOfSensors:-1:1
                        SensorPanels{i} = uipanel(ThermistorGrid, 'BorderType', ...
                            'none', 'TitlePosition', 'centertop', ...
                            'Title', [' T' num2str(i)], 'FontWeight', 'bold', ...
                            'BackgroundColor', [0.94 0.94 0.94]);
                    end
            
            
                % Grid layout for each sensor
                % -----------------------------
                    for i=app.NumberOfSensors-1:-1:1
                        SensorGrids{i} = uigridlayout(SensorPanels{i}, 'ColumnWidth', ...
                            {'100x'}, 'RowHeight', {'100x'}, 'Padding',[5 5 5 5], ...
                            'BackgroundColor', CMap(i,:));
                    end
                    i = max(app.NumberOfSensors);
                    SensorGrids{i} = uigridlayout(SensorPanels{i}, 'ColumnWidth', ...
                            {'100x'}, 'RowHeight', {'100x'}, 'Padding',[5 5 5 5], ...
                            'BackgroundColor', 'k');
                    SensorPanels{i}.Title = 'BW';
            
                % Checkbox for each sensor
                % -----------------------------
            
                  for i=app.NumberOfSensors:-1:1
                          app.Checkboxes{i} = uicheckbox(SensorGrids{i}, 'Text', '', ...
                              'Value',true, 'Tooltip', ['Check to display data from sensor T' num2str(i) ' on plots'], ...
                              'tag',['c_b_' num2str(i)], 'ValueChangedFcn', {@cbSensValueChange, h_axDec(i), h_axCln(i), h_axRaw(i), ...
                              app.checkbox_rawp.Value, app.checkbox_clnp.Value, app.checkbox_decp.Value});
                  end
        loading.Value =1;
        close(loading)
        end

        function axesChildrenOFF(app)
                for k = 1:numel(app.C)
                    S(1).type = '()';
                    S(1).subs = {k};
                    S(2).type = '.';
                    S(2).subs = 'Visible';
                    app.C = subsasgn(app.C, S, 'off');
                end
        end

        function axesChildrenON(app)
                for k = 1:numel(app.C)
                    S(1).type = '()';
                    S(1).subs = {k};
                    S(2).type = '.';
                    S(2).subs = 'Visible';
                    app.C = subsasgn(app.C, S, 'on');
                end
            
        end
        
        function plotLayout(app)
            % Turn all values of plot checkboxes to strings
            dp = num2str(app.DepthPlot);
            ap = num2str(app.AccelPlot);
            tp = num2str(app.TiltPlot);
            % Concatenate strings of values with the letter x so that this
            % string can be used in the grid layout function
            dpx = strcat(dp, 'x');
            apx = strcat(ap, 'x');
            tpx = strcat(tp, 'x');

            app.grid_PlotWindow.RowHeight = {'0.2x','0.27x','5x', dpx, apx, tpx, '0.2x', '0.2x'};

            % Assign all children of each axes to variable
            DC = app.axis_depth.Children;
            AC = app.axis_accel.Children;
            TC = app.axis_tilt.Children;
            
            if app.DepthPlot == 0 && app.AccelPlot == 0 && app.TiltPlot == 0
                app.axis_depth.Visible = 'off';
                app.C = DC;
                app.axesChildrenOFF

                app.axis_accel.Visible = 'off';
                app.C = AC;
                app.axesChildrenOFF

                app.axis_tilt.Visible = 'off';
                app.C = TC;
                app.axesChildrenOFF

            elseif app.DepthPlot == 0 && app.AccelPlot == 1 && app.TiltPlot == 0
                app.axis_depth.Visible = 'off';
                app.C = DC;
                app.axesChildrenOFF

                app.axis_accel.Visible = 'on';
                app.C = AC;
                app.axesChildrenON

                app.axis_tilt.Visible = 'off';
                app.C = TC;
                app.axesChildrenOFF

            elseif app.DepthPlot == 0 && app.AccelPlot == 0 && app.TiltPlot == 1
                app.axis_depth.Visible = 'off';
                app.C = DC;
                app.axesChildrenOFF

                app.axis_accel.Visible = 'off';
                app.C = AC;
                app.axesChildrenOFF

                app.axis_tilt.Visible = 'on';
                app.C = TC;
                app.axesChildrenON

            elseif app.DepthPlot == 1 && app.AccelPlot == 0 && app.TiltPlot == 0
                app.axis_depth.Visible = 'on';
                app.C = DC;
                app.axesChildrenON

                app.axis_accel.Visible = 'off';
                app.C = AC;
                app.axesChildrenOFF

                app.axis_tilt.Visible = 'off';
                app.C = TC;
                app.axesChildrenOFF

            elseif app.DepthPlot == 1 && app.AccelPlot == 1 && app.TiltPlot == 0
                app.axis_depth.Visible = 'on';
                app.C = DC;
                app.axesChildrenON

                app.axis_accel.Visible = 'on';
                app.C = AC;
                app.axesChildrenON

                app.axis_tilt.Visible = 'off';
                app.C = TC;
                app.axesChildrenOFF

            elseif app.DepthPlot == 1 && app.AccelPlot == 0 && app.TiltPlot == 1
                app.axis_depth.Visible = 'on';
                app.C = DC;
                app.axesChildrenON

                app.axis_accel.Visible = 'off';
                app.C = AC;
                app.axesChildrenOFF

                app.axis_tilt.Visible = 'on';
                app.C = TC;
                app.axesChildrenON

            elseif app.DepthPlot == 0 && app.AccelPlot == 1 && app.TiltPlot == 1
                app.axis_depth.Visible = 'off';
                app.C = DC;
                app.axesChildrenOFF

                app.axis_accel.Visible = 'on';
                app.C = AC;
                app.axesChildrenON

                app.axis_tilt.Visible = 'on';
                app.C = TC;
                app.axesChildrenON

            elseif app.DepthPlot == 1 && app.AccelPlot == 1 && app.TiltPlot == 1
                app.axis_depth.Visible = 'on';
                app.C = DC;
                app.axesChildrenON

                app.axis_accel.Visible = 'on';
                app.C = AC;
                app.axesChildrenON

                app.axis_tilt.Visible = 'on';
                app.C = TC;
                app.axesChildrenON
            end

            app.xAlign
           
        end


end
    
    
    methods (Access = public)
        
        function setPenInfo(app, cruise, station, pen, sensor, ...
                datum, instrument, numsens, numactivesens, lat, lon, tilt, depth)
                        % Consistent definitions with ROQ15
            app.Cruisename      = cruise;
            app.StationName     = station;
            app.Penetration     = pen;
            app.ProbeID         = sensor;
            app.Datum           = datum;
            app.LoggerID        = instrument;
            app.NumberOfSensors = numsens;
            app.NoActiveTherm = numactivesens;
            app.Latitude        = lat;
            app.Longitude       = lon;
            app.Tilt_mean       = tilt;
            app.Depth_mean      = depth;

            app.label_Cruise.Text = app.Cruisename;
            app.label_Station.Text = app.StationName;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


   % Adjust font sizes and grid layouts if on one laptop screen
   % -----------------------------------------------------------
    ScreenSize = get(0,'ScreenSize');
    
    if ScreenSize(3) <= 1440 && ScreenSize(4) == 900
        app.panel_info.FontSize = 16;
        app.panel_selected.FontSize = 16;
        app.panel_plotcontrols.FontSize = 16;
        app.panel_create.FontSize = 16;

        
    end


   % Make main figure full screen %
        app.figure_Main.WindowState = 'maximized';
   
   % INITIAL CONDITIONS %
       % Penetration Info %
        app.penetrationInfo = ["", "", "", "", "", "", "", "" ...
            "", "", ""];

        % Vertical line begins at x = 0
        enableDefaultInteractivity(app.axis_raw);
           
        % Intialize Structure % 
        app.S_Axes = struct('Raw',app.axis_raw,'Depth',app.axis_depth,...
        'Tilt',app.axis_tilt, 'Accel', app.axis_accel); 

        % Structure Containing Axes Limits
        app.S_AxLims = struct('AxLims_Raw',app.H_AxLims_Raw,...
        'AxLims_Depth',app.H_AxLims_Depth,...
        'AxLims_Tilt',app.H_AxLims_Tilt,'AxLims_Acc',app.H_AxLims_Acc);

        % Structure Containing Executable Control Handles and Filter Window Lengths
        app.S_Exe_Controls = struct('Load',app.button_restart,...
        'Export_Penfile',app.button_SetPen);

        % Structure Containing Handles for Selection Record Numbers
        app.S_Selections = struct('Select',app.dropdown_Select,...
        'Start_Eqm',app.edit__StartEqm,'End_Eqm',app.edit_EndEqm,'Start_Pen',...
         app.edit_PenStart,'Start_Heat',app.edit_HeatPulse,'End_Pen',app.edit_End);

        % Structure Containing Plot Control Handles and Quit
        app.S_Plot_Controls = struct('RawPlot',app.checkbox_rawp,'CleanPlot',app.checkbox_clnp','DecimatedPlot',app.checkbox_decp,...
        'Quit',app.button_quit,'Legend',app.H_LegendSelect);

        %Structure Containing Handles for Selection Vertical Line Plotting
        app.S_Selections_Lines = struct('Raw',app.H_Selected_raw,'Depth',app.H_Selected_depth',...
        'Tilt',app.H_Selected_tilt,...
        'Raw_Text',app.H_Selected_raw_text,...
        'Depth_Text',app.H_Selected_depth_text,'Tilt_Text',app.H_Selected_tilt_text);
        
        app.S_VerticalLines = struct('Start_Eqm_Line', app.SE_VerticalLine, ...
        'End_Eqm_Line', app.EE_VerticalLine, 'Start_Pen_Line', app.SP_VerticalLine, ...
        'Start_Heat_Line', app.HP_VerticalLine, 'End_Pen_Line', app.EP_VerticalLine);

        % Structure Containing Datafile information
        app.S_FileInfo = struct('Filename',app.edit_filename,'Start_Date',app.edit_start_date,...
        'Start_Time',app.edit_start_time,'End_Time',app.edit_end_time,...
        'Log_Interval',app.edit_logint,'Decay_Time',app.edit_decay, 'Pulse_Power', app.edit_pulse_W,...
        'Pulse_Length',app.edit_pulse_s,...
        'No_Thermistors',app.H_NoThermistors);

        % ----- Structure Containing All Previous Structures ----- %
        app.H = struct('Fileinfo',app.S_FileInfo,'Axes',app.S_Axes,...
        'Exe_Controls',app.S_Exe_Controls,...
        'Selections',app.S_Selections,'Selections_Lines',app.S_Selections_Lines,...
        'Plot_Controls',app.S_Plot_Controls,'Axis_Limits',app.S_AxLims,...
        'Error',app.ErrorFlag,'Crop',app.CropFlag);

        pause(2)

        % Initialize array with heat pulse data
        app.HeatPulsePens = [];
        Pulse = app.checkbox_UseHP.Value;

        if Pulse == 1
            app.PulseData = 1;
        elseif Pulse == 0
            app.PulseData = 0;
        end


        % Disable all buttons but LOAD %
        %app.H_Delete.Enable = 'off';
        %app.H_export_ascii.Enable = 'off';
        app.button_SetPen.Enable = "off";
        app.export_pen.Enable = 'off';
        %app.H_export_ascii.Enable = "off";
        

        % Make all edit fields uneditable except filter window %
        %app.edit_filename.Editable = 'off';
        app.edit_start_time.Editable = 'off';
        app.edit_logint.Editable = 'off';
        app.edit_start_date.Editable = 'off';
        app.edit_end_time.Editable = 'off';
        app.edit_decay.Editable = 'off';
        app.edit__StartEqm.Editable = 'on';
        app.edit_EndEqm.Editable = 'off';
        app.edit_PenStart.Editable = 'off';
        app.edit_HeatPulse.Editable = 'off';
        app.edit_End.Editable = 'off';
        app.edit_pulse_s.Editable = 'off';
        app.edit_pulse_W.Editable = 'off';
        
        
        % INITIALIZE %
        % Initial zeros or NaN
        app.H_NoThermistors = app.NoTherm;
        app.All_Zeros = zeros(1,app.NoTherm);
        app.All_NaN = app.All_Zeros/0;
        
        % Set Initial NaN conditions %
        app.H_Selected_raw = app.All_NaN;
        app.H_Selected_depth = app.All_NaN;
        app.H_Selected_accel = app.All_NaN;
        app.H_Selected_tilt = app.All_NaN;
        app.H_Selected_raw_text = app.All_NaN;
        app.H_Selected_depth_text = app.All_NaN;
        app.H_Selected_accel_text = app.All_NaN;
        app.H_Selected_tilt_text = app.All_NaN;
        app.H_LegendSelect = app.All_NaN;
        app.H_AxLims_Raw = app.All_NaN;
        app.H_AxLims_Tilt = app.All_NaN;
        app.H_AxLims_Depth = app.All_NaN;
        app.H_AxLims_Acc = app.All_NaN;
        app.selTime_Vector = app.All_NaN;

        % Turn all plots on and only raw data visible %
        app.checkbox_rawp.Value = 1;
        app.checkbox_clnp.Value = 1;
        app.checkbox_decp.Value = 1;
        app.axis_depth.Visible="on";
        app.checkbox_depthplot.Value = 1;
        app.DepthPlot = 1;
        app.checkbox_tiltplot.Value = 1;
        app.axis_tilt.Visible='on';
        app.TiltPlot = 1;
        app.checkbox_accelplot.Value=1;
        app.axis_accel.Visible="on";
        app.AccelPlot = 1;


        % Set Initial Zero Conditions %
        Traw = 0;
        Tcln = 0;
        Pitch = 0;
        Roll = 0;
        G = 0;
        Tilt = 0;
        Time = 0;
        app.ErrorFlag = 0;
        app.CropFlag = 0;

        % Initial Paramete labels %
        app.Params = {'text'};

        % Set Current path
        % -----------------
        CurrentPath = pwd;
        app.label_currentpathfull.Text = ['...' CurrentPath(end-20:end)];


        end

        % Button pushed function: button_ResetSelection
        function button_ResetSelectionPushed(app, event)
            
        % Reset all selections
        % ---------------------
            delete(app.SE_VerticalLine);
            delete(app.EE_VerticalLine);
            delete(app.SP_VerticalLine);
            delete(app.HP_VerticalLine);
            delete(app.EP_VerticalLine);
            delete(app.SE_depthLine)
            delete(app.EE_depthLine)
            delete(app.SP_depthLine)
            delete(app.HP_depthLine)
            delete(app.EP_depthLine)
            delete(app.SE_accelLine)
            delete(app.EE_accelLine)
            delete(app.SP_accelLine)
            delete(app.HP_accelLine)
            delete(app.EP_accelLine)
            delete(app.SE_tiltLine)
            delete(app.EE_tiltLine)
            delete(app.SP_tiltLine)
            delete(app.HP_tiltLine)
            delete(app.EP_tiltLine)
            app.H.Selections.Value=app.dropdown_Select.Items(1);
            app.H.Selections.Start_Eqm.Value='';
            app.H.Selections.End_Eqm.Value='';
            app.H.Selections.Start_Pen.Value='';
            app.H.Selections.Start_Heat.Value='';
            app.H.Selections.End_Pen.Value='';

        end

        % Value changed function: dropdown_Select
        function dropdown_SelectValueChanged(app, event)
        % Call function for selections
        app.SelectVerticalLines

        % If start and end times for penetration are selected, enable 'set
        % penetration' button
        PVL = [app.SP_VerticalLine app.EP_VerticalLine];
        if all(ishandle(PVL))
            app.button_SetPen.Enable = "on";
        end

        end

        % Button pushed function: button_restart
        function button_restartButtonPushed(app, event)

    %% Reset all
    %% =========
            %% RESET CONTROLS
            app.ErrorFlag=0;
            app.H.Exe_Controls.Delete.Enable='off';
            app.H.Plot_Controls.RawPlot.Enable='off';
            app.H.Plot_Controls.CleanPlot.Enable='off';
            app.H.Plot_Controls.DecimatedPlot.Enable='off';
            
            % Initialize Selection Vectors for 22 if No_Thermistors hasn't been
            % populated yet
            app.All_Zeros = zeros(1,app.NoTherm);
            app.All_NaN = app.All_Zeros/0;
            
            %% CLEAR AXES
            
            cla(app.H.Axes.Raw,'reset');
            cla(app.H.Axes.Depth,'reset');
            cla(app.H.Axes.Tilt,'reset');
            cla(app.H.Axes.Accel,'reset');

            %% RESET ALL COMPONENTS %%
                app.H.Selections_Lines.Raw                   = app.All_Zeros;
                app.H.Selections_Lines.Depth                 = app.All_Zeros;
                app.H.Selections_Lines.Tilt                  = app.All_Zeros;
                app.H.Selections_Lines.Accel                 = app.All_Zeros;
                app.H.Selections_Lines.Raw_Text              = app.All_Zeros;
                app.H.Selections_Lines.Depth_Text            = app.All_Zeros;
                app.H.Selections_Lines.Tilt_Text             = app.All_Zeros;
                app.H.Selections_Lines.Accel_Text            = app.All_Zeros;

            % Reset Export/Write Buttons
                app.H.Exe_Controls.Export_Penfile.Enable='off';
                %app.H.Exe_Controls.Export_Ascii.Enable='off';

            % Reset Datafile Information
                app.H.Fileinfo.Filename.Value = '';
                app.H.Fileinfo.Start_Date.Value='';
                app.H.Fileinfo.Start_Time.Value='';
                app.H.Fileinfo.End_Time.Value='';
                app.H.Fileinfo.Log_Interval.Value='';
                app.H.Fileinfo.Pulse_Length.Value='';
                app.H.Fileinfo.Pulse_Power.Value='';
                app.H.Fileinfo.Decay_Time.Value='';

            % Reset Selection info
                app.H.Selections.Select.Enable='on';
                app.H.Selections.Value=app.dropdown_Select.Items(1);
                app.H.Selections.Start_Eqm.Value='';
                app.H.Selections.End_Eqm.Value='';
                app.H.Selections.Start_Pen.Value='';
                app.H.Selections.Start_Heat.Value='';
                app.H.Selections.End_Pen.Value='';


        end

        % Button pushed function: edit_load
        function edit_loadPushed(app, event)
    %% Reset all
    %% =========
            %% RESET CONTROLS
            app.ErrorFlag=0;
            app.H.Exe_Controls.Delete.Enable='off';
            app.H.Plot_Controls.RawPlot.Enable='off';
            app.H.Plot_Controls.CleanPlot.Enable='off';
            app.H.Plot_Controls.DecimatedPlot.Enable='off';
            
            % Initialize Selection Vectors for 22 if No_Thermistors hasn't been
            % populated yet
            app.All_Zeros = zeros(1,app.NoTherm);
            app.All_NaN = app.All_Zeros/0;
            
            %% CLEAR AXES
            
            cla(app.H.Axes.Raw,'reset');
            cla(app.H.Axes.Depth,'reset');
            cla(app.H.Axes.Tilt,'reset');
            cla(app.H.Axes.Accel,'reset');

            %% RESET ALL COMPONENTS %%
                app.H.Selections_Lines.Raw                   = app.All_Zeros;
                app.H.Selections_Lines.Depth                 = app.All_Zeros;
                app.H.Selections_Lines.Tilt                  = app.All_Zeros;
                app.H.Selections_Lines.Accel                 = app.All_Zeros;
                app.H.Selections_Lines.Raw_Text              = app.All_Zeros;
                app.H.Selections_Lines.Depth_Text            = app.All_Zeros;
                app.H.Selections_Lines.Tilt_Text             = app.All_Zeros;
                app.H.Selections_Lines.Accel_Text            = app.All_Zeros;

            % Reset Export/Write Buttons
                app.H.Exe_Controls.Export_Penfile.Enable='off';
                %app.H.Exe_Controls.Export_Ascii.Enable='off';

            % Reset Datafile Information
                app.H.Fileinfo.Filename.Value = '';
                app.H.Fileinfo.Start_Date.Value='';
                app.H.Fileinfo.Start_Time.Value='';
                app.H.Fileinfo.End_Time.Value='';
                app.H.Fileinfo.Log_Interval.Value='';
                app.H.Fileinfo.Pulse_Length.Value='';
                app.H.Fileinfo.Pulse_Power.Value='';
                app.H.Fileinfo.Decay_Time.Value='';

            % Reset Selection info
                app.H.Selections.Select.Enable='on';
                app.H.Selections.Value=app.dropdown_Select.Items(1);
                app.H.Selections.Start_Eqm.Value='';
                app.H.Selections.End_Eqm.Value='';
                app.H.Selections.Start_Pen.Value='';
                app.H.Selections.Start_Heat.Value='';
                app.H.Selections.End_Pen.Value='';


     % This is Mike's filtering. We need this until we see what raw data 
     % the new probe is producing. Can remove this filtering or modify it
     % once we know more.
     % ----------------------------------------------------------------
            wlmedian = app.edit_MedianFilter.Value;
            wlmean = app.edit_MeanFilter.Value;
            wldec = app.edit_DecimateFilter.Value;

             if rem(wlmean,2)~=0
                uialert(app.figure_Main, 'Mean window length must be even number',...
                'Filter Error');
                app.ErrorFlag=1;
                return
             end

        % % INPUT FILE
        % Open gui to select filename
        [filename, pathname] = uigetfile({'*.raw';'*.mat'},'Pick a raw data probe output file');
        figure(app.figure_Main)

        % Validate the file/path
        if isequal(filename,0)||isequal(pathname,0)
            uialert(app.figure_Main, 'File not found or not valid',...
                'Error finding file');
            app.ErrorFlag=1;
            return
        else
            f=fullfile(pathname,filename);
        
            % See if it's a mat file or a raw file
            [~,fn,ext]=fileparts(filename);
            if strcmp(ext,'.mat')
                load(f,'DATA')
            else
                % Get the first line and confirm default header start
                fid = fopen(f,'r');
                if fid<0
                    uialert(app.figure_Main, 'Unable to open selected file','Error');
                    app.ErrorFlag=1;
                    return
                else
                    line = fgetl(fid);
                    if isempty(line)||~strcmp(line(1),'$')
                        fclose(fid);
                        disp('Invalid File Format');
                        app.ErrorFlag=1;
                        return
                    else
                        % Read in Header Here (TO DO) --> don't understand this. It just makes line equal to the 12th line. Why the loop? - KD
                        for n=1:12
                            line = fgetl(fid);
                        end
                
                        % Determining NUMBER OF THERMISTORS - KD
                            % Option 1 (allow user to manually input number of thermistors
                                %app.H.Fileinfo.No_Thermistors.Value = app.NoTherm; % Set the No_Thermistors in H.Fileinfo Structure
                            % Option 2 (Mike's):
                                % Read second line and determine number of thermistors 
                                % There are 11 columns of timing, acceleration, etc.
                                % Add 1 for bottom water sensor
                                line         = strtrim(fgetl(fid)); %--> don't understand this. Now it just makes line equal to the 13th line. What was the point of the loop above?
                                ncols        = length(find(isspace(line)));
                                nThermistors = ncols-11+1;
                                app.H.Fileinfo.No_Thermistors.Value = nThermistors;  % Set the No_Thermistors in H.Fileinfo Structure
                                app.NoTherm = nThermistors;
                                app.NumSensUsed = app.NoTherm;
                                app.edit_NumberofTherm.Value = num2str(nThermistors);

                        % Fill properties that create vectors of zeros or NaNs the size of the number of thermistors (called foo in Mike's code)
                        app.All_Zeros = zeros(1,app.H.Fileinfo.No_Thermistors.Value);
                        app.All_NaN = app.All_Zeros/0;
                    end
                end
                fclose(fid);

       % % ---------------------  LOAD DATA --------------------- % %
       % CHECK FOR UNIX - USE AWK IF POSSIBLE
       if isunix

                %%p51_LoadUnix%%
                [~,fn,~]=fileparts(f);
        
                    %%p51_ScanUnix%%
                    if exist('RawData.tmp','file')
                    delete('RawData.tmp');
                    end
                    % Grab all lines with length > 80 (Data)
                    eval(['!awk ''length > 80'' ',f,' > RawData.tmp'])
                    % Grab all Acomms Report lines
                    eval(['!awk ''/RPT/'' ',f,' > ',fn,'_HReport.dat'])
                    % Grab all Heat Pulse Report lines
                    eval(['!awk ''/HSTART/'' ',f,' > ',fn,'_HPulse.dat'])
                    
                    % Prep textscan for variable number of thermistors
                  
                    n  = repmat('%n ',1,app.NoTherm); %Thermistors
                    s       = repmat('%s ',1,11);         % Time, Acc, depth
                    fid = fopen('RawData.tmp','r');
                    C   = textscan(fid,[s n]);
                    fclose(fid);
        
                    % Initialize Matrix AllTdata (All Temperature Data)
                    l    = length(C{1});
                    Traw = NaN*zeros(app.NoTherm+1,l);
        
                    % Assign
                    ymd    = str2num(char(strrep(C{3},'-',' ')));
                    hms    = str2num(char(strrep(C{4},':',' ')));
                    accx   = str2num(char(strrep(C{6},'X','')));
                    accy   = str2num(char(strrep(C{7},'Y','')));
                    accz   = str2num(char(strrep(C{8},'Z','')));
                    pwr    = str2num(char(strrep(C{9},'V','')));
                    z      = str2num(char(strrep(C{10},'D','')));
        
                    for i=1:app.NoTherm
                        foo = C{11+i}';
                        Traw(i,1:length(foo))=foo;
                    end
        
                    % Restructure Traw with T1 in row 1, incrementing to Twater
                    Traw(app.NoTherm+1,:)=Traw(1,:);
                    Traw=Traw(2:end,:);
                    %%END of p15_ScanUnix%%
      
                % % QC. Error if number of temperature measurements ~= time. Prompt for CleanRawFile
                if length(Traw)~=length(hms)
                    errorstr='Timing Error. Click OK to attempt to clean';
                        dlgname = 'Timing Error';
                    choice = uiconfirm(app.figure_Main, errorstr, dlgname, ...
                        'Options', {'OK','Cancel'});
                    switch choice
                    case 'Cancel'
                        fclose all;close all hidden; clear
                    case 'OK'
                        CleanRawFile(f)
                        [ymd,hms,accx,accy,accz,pwr,z,Traw] = p51_ScanUnix(app.H,f,fn);
                    end
                end
        
        
                % Remove final timestep from temperature data (logger could have been
                % turned off with a partial line)


                % % Parse Timing
                yr    = ymd(:,1);
                mo    = ymd(:,2);
                dy    = ymd(:,3);
                hr    = hms(:,1);
                mn    = hms(:,2);
                sc    = hms(:,3);

                % % Read in Heat Pulse and Report Data
                HSTART = {};
                fid = fopen([fn,'_HPulse.dat'],'r');
                i=1;
                while i>0
                    line = fgetl(fid);
                    if line == -1
                        fclose(fid);
                        isave=i-1;
                        i=0;
                        break
                    else
                        HSTART{i}=strtrim(line);
                        i=i+1;
                    end
                end

                RPT = {};
                fid = fopen([fn,'_HReport.dat'],'r');
                i=1;
                while i>0
                    line = fgetl(fid);
                    if line==-1
                        fclose(fid);
                        i=0;
                        break
                    else
                        RPT{i}=strtrim(line);
                        i=i+1;
                    end
                end


                % % Read in Header and Assign Parameters
                fid=fopen(f,'r');

                % Discard Description and first parameter line (no longer used)
                line = fgetl(fid); line = fgetl(fid);

                % Remaining Header Lines
                headerlines = 11;

                for i=1:headerlines
                    line = strtrim(fgetl(fid));
                    a    = find(isspace(line)==1);
                    Params{i}     = strtrim(line(1:a(end)));
                    ParamsVals(i) = str2num(line(a(end):end));
                end
                fclose(fid);

                % Store Header in Structure PARAMETERS
                app.Params     = Params;
                app.ParamsVals = ParamsVals;
                app.Parameters = struct('Field',{app.Params},'Value',app.ParamsVals);

                % % Remove Temporary Files Generated by AWK
                if exist('RawData.tmp','file')
                    delete('RawData.tmp');
                end

            % move DAT files to subfolder
            movefile *.dat outputs
                
       
        %%END of p15_LoadUnix%%
       else

           %%START of p51_LoadNonUnix
                % Break apart input file 'f'
                [~,fn,ext] = fileparts(f);
                filename   = [fn ext];

                %% ---------------------  START READING DATA - NOT UNIX --------------------- %%
                %

                % INITIALIZE VARIABLES
                % Time Series Variables
                yr    = [];
                mo    = [];
                dy    = [];
                hr    = [];
                mn    = [];
                sc    = [];
                accx  = [];
                accy  = [];
                accz  = [];
                pwr   = [];
                z     = [];
                T0    = [];
                T1    = [];
                T2    = [];
                T3    = [];
                T4    = [];
                T5    = [];
                T6    = [];
                T7    = [];
                T8    = [];
                T9    = [];
                T10   = [];
                T11   = [];
                T12   = [];
                T13   = [];
                T14   = [];
                HSTART = {};
                RPT = {};

                % Header Parameters
                Params    = {};
                ParamVals = [];
                Record    = [];
                
                %% QC Valid ROQ Heat Flow Probe Log File
                fid  = fopen(f,'r');
                line = fgetl(fid);
                if length(line)~=21
                    fclose(fid);
                    uialert(app.figure_Main, 'This RAW file has the incorrect format.','Error');
                    ErrorFlag=1;
                    return
                end

                %% READ HEADER
                % Discard Description and first parameter line (no longer used)
                frewind(fid);
                line = fgetl(fid); line = fgetl(fid);

                % Remaining Header Lines
                headerlines = 11;

                % Read Header
                for i=1:headerlines
                    line = strtrim(fgetl(fid));
                    a    = find(isspace(line)==1);
                    Params{i}     = strtrim(line(1:a(end)));
                    ParamsVals(i) = str2num(line(a(end):end));
                end

                % Store Header in Structure PARAMETERS
                app.Params     = Params;
                app.ParamsVals = ParamsVals;
                app.Parameters = struct('Field',{app.Params},'Value',app.ParamsVals);

                %% Waitbar
                foo = strrep(filename,'_','-');
                h_wait = waitbar(0,['Loading ',foo]);
                set(h_wait,'name','Please Wait')
                k_wait = 1;

                %% READ REMAINING DATA
                i=1; % Valid Data Counter
                n=1; % Other Command Counter
                while i>0
                    line = fgetl(fid);
                    if line == -1
                        fclose(fid);
                        i=0;
                        break
                    else
                        % Check for Data Line or Comment Line
                        a = find(isspace(line)==1);
                        if ~contains(line,'!') && length(a)==25
                            % Data Line
                            v1  = line(1:a(1)-1);
                            v2  = line(a(1)+1:a(2)-1);
                            v3  = line(a(2)+1:a(3)-1);
                            v4  = line(a(3)+1:a(4)-1);
                            v5  = line(a(4)+1:a(5)-1);
                            v6  = line(a(5)+1:a(6)-1);
                            v7  = line(a(6)+1:a(7)-1);
                            v8  = line(a(7)+1:a(8)-1);
                            v9  = line(a(8)+1:a(9)-1);
                            v10 = line(a(9)+1:a(10)-1);
                            v11 = line(a(10)+1:a(11)-1);
                            v12 = line(a(11)+1:a(12)-1);
                            v13 = line(a(12)+1:a(13)-1);
                            v14 = line(a(13)+1:a(14)-1);
                            v15 = line(a(14)+1:a(15)-1);
                            v16 = line(a(15)+1:a(16)-1);
                            v17 = line(a(16)+1:a(17)-1);
                            v18 = line(a(17)+1:a(18)-1);
                            v19 = line(a(18)+1:a(19)-1);
                            v20 = line(a(19)+1:a(20)-1);
                            v21 = line(a(20)+1:a(21)-1);
                            v22 = line(a(21)+1:a(22)-1);
                            v23 = line(a(22)+1:a(23)-1);
                            v24 = line(a(23)+1:a(24)-1);
                            v25 = line(a(24)+1:end);

                            % Assign
                            %     Yr Mo Dy
                            v  = str2num(strrep(v1,'-',' '));
                            yr(i,:) = v(1);
                            mo(i,:) = v(2);
                            dy(i,:) = v(3);
                            %     Hr Min Sec
                            v  = str2num(strrep(v4,':',' '));
                            (strrep(v4,':',' '));
                            hr(i,:) = v(1);
                            mn(i,:) = v(2);
                            sc(i,:) = v(3);
                            %     Acceleration
                            accx(i,:) = str2num(strrep(v6,'X',''));
                            accy(i,:) = str2num(strrep(v7,'Y',''));
                            accz(i,:) = str2num(strrep(v8,'Z',''));
                            %     Voltage
                            pwr(i,:)  = str2num(strrep(v9,'V',''));
                            %     Depth
                            z(i,:)    = str2num(strrep(v10,'D',''));
                            %     Thermistors
                            T0(i,:)   = str2num(v12);
                            T1(i,:)   = str2num(v13);
                            T2(i,:)   = str2num(v14);
                            T3(i,:)   = str2num(v15);
                            T4(i,:)   = str2num(v16);
                            T5(i,:)   = str2num(v17);
                            T6(i,:)   = str2num(v18);
                            T7(i,:)   = str2num(v19);
                            T8(i,:)   = str2num(v20);
                            T9(i,:)   = str2num(v21);
                            T10(i,:)  = str2num(v22);
                            T11(i,:)  = str2num(v23);
                            T12(i,:)  = str2num(v24);
                            T13(i,:)  = str2num(v25);

                            Traw  = [T1'; T2'; T3'; T4'; T5'; T6'; T7'; T8'; T9'; T10'; T11'; T12'; T13'; T0'];

                            % Increment
                            i=i+1;
                        else
                          %  Other{n}=line;
                          %  n=n+1;
                        end
                    end
                    waitbar(k_wait/5000,h_wait);
                    if k_wait==5000
                        k_wait=0;
                    end
                    k_wait=k_wait+1;
                end
                close(h_wait);

            %% END of p51_LoadNonUnx %%
       end


        % % ACCELERATION, TILT
        
        % Calculate TILT %
            % Vertical Acceleration: -256 --> +256 == -2g --> + 2g
            G = accz*(2/256);

            % Vertical Acceleration deviation from 1 G
            Gdev = abs(1-abs(G));

            % Calculate Tilt : -1G = 0 deg; 0G = 90 deg
            vert = 0; % when probe is vertical
            hor = 90; % when probe is horizontal
            devMin = 0; % minumum deviation from vertical
            devMax = 1; % maximum deviation from vertical
            
            Tilt = vert-((Gdev-devMin))*(vert-hor)./(devMax-devMin);
         
        % END p51_Acceleration(accx,accy,accz)


        % % QC DEPTH
        a=find(z<0); % changed Z's that are less than 0 to NaN
        if ~isempty(a)
            z(a)=NaN;
        end
        
        % QC Year
        if yr(1)==0
            yr(1:end)=21;
        end
            
        if yr(1)<2000
            yr=yr+2000;
        end

        % % ----------------------------------------------- % %
        %                                                   %
        %                2020 Filtering Change              %
        %                                                   %
        %     Take only Unique time values                  %                           %
        %                                                   %
        
        timeNum  = datenum(yr,mo,dy,hr,mn,sc); 
        a        = find(unique(timeNum));  % To handle duplicate times  - KD
        timeNumU = timeNum(a);
        accxU    = accx(a);
        accyU    = accy(a);
        acczU    = accz(a);
        pwrU     = pwr(a);
        tiltU    = Tilt(a);
        gU       = G(a);
        zU       = z(a);
        TrawU    = Traw(:,a);
       
        
        % % Clean / Filter / Decimate

        % START p51_CFD(Traw, wlmedian,wlmean,wldec,app.NoTherm,timeNumU)
        
            Tallf1    = Traw;
            b         = Tallf1<=0; % I changes this variable from a to b so that the variable a above remains the same (bc is used later)
            Tallf1(b) = NaN;
            
            % % Despike by taking running median
            for i = 1:app.NoTherm
                eval(['Tallf2(',int2str(i),',:)=movmedian(Tallf1(',int2str(i),',:),wlmedian);'])
            end
            % % Smooth by taking running mean
            for i = 1:app.NoTherm
                eval(['Tallf2(',int2str(i),',:)=rbmmed(Tallf2(',int2str(i),',:),wlmean);'])
            end
            
            % Assign median filtered values to matrix Tcln (CLEAN)
            Tcln = Tallf2;

            % Flip time
            timeNumU = timeNumU';
            
            % Decimate and assign values to matrix Tdec (DECIMATED)
            n=1:wldec:length(Tcln(1,:));
            Tdec=Tcln(:,n);
            timeDec=timeNumU(n);
          
         % END START p51_CFD %


        % % TIMING
        % Create Datenumber & Record Counter
        timestr = datestr(timeNumU);
        
        Time = timeNumU;

        Record       = 1:1:length(Time);
        RecordU      = Record(a);


        % % ASSIGN DECIMATED DATA

        n          = 1:wldec:length(TrawU);
        Time_dec   = timeDec;
        Record_dec = RecordU(n);
        %Pitch_dec  = pitchU(n);
        %Roll_dec   = rollU(n);
        G_dec      = gU(n);
        Tilt_dec   = tiltU(n);
        Power_dec  = pwrU(n);
        Depth_dec  = zU(n);

        % % DISPLAY ACOMMS DATA - HEAT PULSE AND JOULES
        HPDATA = [HSTART' RPT'];

        % % FOLD DATA INTO STRUCTURE

        % Store Acoustic Comms in Structure Acomms
        S_Acomms = struct('Acomms',{HPDATA});

        app.DATA = struct('Traw',TrawU,'Tcln',Tcln,'Tdec',Tdec,...
            'G',gU,'G_dec',G_dec,'Tilt',tiltU,'Tilt_dec',Tilt_dec,...
            'Time',timeNumU,'Time_dec',Time_dec,...
            'Record',RecordU,'Record_dec',Record_dec,...
            'Power',pwrU,'Power_dec',Power_dec,...
            'Depth',zU,'Depth_dec',Depth_dec,...
            'Acc_x',accxU,...
            'Acc_y',accyU,...
            'Acc_z',acczU,...
            'Parameters',{app.Parameters},...
            'Acomms',{HPDATA});

            end
        end


    % % ASSIGN FILE INFO
    app.H.Fileinfo.Start_Date.Value=datestr(app.DATA.Time(1),2);
    app.H.Fileinfo.Start_Time.Value=datestr(app.DATA.Time(1),13);
    app.H.Fileinfo.End_Time.Value=datestr(app.DATA.Time(end),13);
    %H.Fileinfo.Filename.String=filename(11:end); % Cut off typical client_FUG17_...
    app.H.Fileinfo.Filename.Value=filename;
    app.label_Station.Text = filename;
    app.H.Fileinfo.HFID=filename(17:end);
    app.H.Fileinfo.Log_Interval.Value=num2str(app.DATA.Parameters.Value(6));
    app.H.Fileinfo.Decay_Time.Value=num2str(app.DATA.Parameters.Value(5));
    app.H.Fileinfo.Pulse_Length.Value=num2str(app.DATA.Parameters.Value(3));
    app.H.Fileinfo.Pulse_Power.Value=num2str(app.DATA.Parameters.Value(4));

    % Extract power of heat pulse in Joulse and Joules/meter from each heat
    % pulse fired
    [NumofHP, ~] = size(app.DATA.Acomms); % Get number of heat pulse fires
   

    for i = 1:NumofHP
        PulseRPT(i) = string(app.DATA.Acomms{i,2});
        app.PulsePower(i) = str2double(extractAfter(PulseRPT(i), 'J'));
    end

% START p51_Plot_ROQ(H,DATA) %
    app.PlotData
    figure(app.figure_Main)


        end

        % Button pushed function: button_BeginSelections
        function button_BeginSelectionsButtonPushed(app, event)
            app.SelectVerticalLines
        end

        % Value changed function: checkbox_decp
        function checkbox_decpValueChanged(app, event)
        DecValue = app.checkbox_decp.Value;
            %p51_PlotSelect toggles Decimated, Clean, and Raw Data plot on/off
        if DecValue == 0
            for i=1:app.H.Fileinfo.No_Thermistors.Value
                set(app.H.Plot_Controls.DecimatedPlot.UserData(i),'Visible','off');
            end
        else
            %set(H.Plot_Controls.Legend.UserData(1),'Visible','on');
            for i=1:app.H.Fileinfo.No_Thermistors.Value
                set(app.H.Plot_Controls.DecimatedPlot.UserData(i),'Visible','on');
            end
        end

        end

        % Value changed function: checkbox_clnp
        function checkbox_clnpValueChanged(app, event)
        CleanValue = app.checkbox_clnp.Value;
            % Clean Plot
        if CleanValue == 0
            for i=1:app.H.Fileinfo.No_Thermistors.Value
                set(app.H.Plot_Controls.CleanPlot.UserData(i),'Visible','off');
            end
        else
            %set(H.Plot_Controls.Legend.UserData(2),'Visible','on');
            for i=1:app.H.Fileinfo.No_Thermistors.Value
                set(app.H.Plot_Controls.CleanPlot.UserData(i),'Visible','on');
            end
        end
        end

        % Value changed function: checkbox_rawp
        function checkbox_rawpValueChanged(app, event)
        RawValue = app.checkbox_rawp.Value;
        if RawValue == 0
            for i=1:app.H.Fileinfo.No_Thermistors.Value
                set(app.H.Plot_Controls.RawPlot.UserData(i),'Visible','off');
            end
        else
            %set(H.Plot_Controls.Legend.UserData(3),'Visible','on');
            for i=1:app.H.Fileinfo.No_Thermistors.Value
                set(app.H.Plot_Controls.RawPlot.UserData(i),'Visible','on');
            end
        end
        end

        % Button pushed function: export_pen
        function export_penButtonPushed(app, event)
       % Create a vector with all penetrations and if they have a heat
       % pulse or not

       app.HeatPulsePens = [app.HeatPulsePens app.PulseData];
                        
       % Set input values %   
            Depth = app.DATA.Depth_dec;
            Depth = Depth';
            Time = app.DATA.Time_dec;
            Tilt = app.DATA.Tilt_dec;
            record = app.DATA.Record_dec;
            record = record';

       % Define penetration measurement Start and End %
            msmt_start = datenum(app.H.Selections.Start_Pen.Value, 'mm/dd/yyyy HH:MM:SS');
            msmt_end = datenum(app.H.Selections.End_Pen.Value, 'mm/dd/yyyy HH:MM:SS');

         % Check year and correct %
            loggerStart = datevec(datestr(Time));
            loggerYear = loggerStart(1,1);
            if loggerYear < 2021
                loggerStart(:,1)=loggerStart(:,1)+2000;
                Time=datenum(loggerStart);
            end
         
          % Find mean depth and tilt records within measurement period
               a = find(Time>=msmt_start & Time<=msmt_end);
               if ~isempty(a)
                   pen_depth = mean(Depth(a), 'omitnan');
                   pen_tilt = mean(Tilt(a), 'omitnan');
                   pen_tilt = sprintf('%2.1f', pen_tilt);
                   pen_tilt = str2double(pen_tilt);
               else
                   uialert(app.figure_Main, 'No data in time window', 'Error')
                   return
               end
          

           % WRITE PENFILE CODE %

            % Temperature Data
            T      = app.DATA.Tdec;
            Tilt   = app.DATA.Tilt_dec;
            Depth  = app.DATA.Depth_dec;
            record = app.DATA.Record_dec;
            Time   = app.DATA.Time_dec;

            % Penfile Name
            cruise_name     = app.Cruisename;
            stn_name        = app.StationName;
            pen_name        = app.Penetration;
            penfile_name    = [cruise_name , '_', stn_name, '_', pen_name];


            PenetrationRecord_time = datenum(app.H.Selections.Start_Pen.Value,'mm/dd/yy HH:MM:SS'); % START of PEN
            HeatPulseRecord_time   = datenum(app.H.Selections.Start_Heat.Value,'mm/dd/yy HH:MM:SS'); % START of HEAT PULSE
            EndRecord_time         = datenum(app.H.Selections.End_Pen.Value,'mm/dd/yy HH:MM:SS'); % END of entire RECORD

            BottomWaterStart_time   = datenum(app.H.Selections.Start_Eqm.Value,'mm/dd/yy HH:MM:SS'); % START of bottom water CALIRBATION period
            BottomWaterEnd_time     = datenum(app.H.Selections.End_Eqm.Value,'mm/dd/yy HH:MM:SS'); % END of bottom water CALIBRATION period

            % Variable HeatPulseRecord will be empty if there is no heat pulse. Set to
            % zero
            if isempty(HeatPulseRecord_time)
                HeatPulseRecord = '0';
            end

            % Penfile Records
            % Variables for indexing, all DECIMATED TIME INDICES
                a = find(Time>=BottomWaterStart_time & Time<=EndRecord_time); % From start of calibration to end of record
                b = find(Time<=PenetrationRecord_time); % Prior to penetration (only in bottom water)
                c = find(Time>=PenetrationRecord_time & Time<=EndRecord_time); % From start of penetration to end of record (end of record = end of penetration)

            PenfileRecords = record(a); % records from start of calibration to end of record
            PenfileTemps   = T(1:app.NoTherm,a); % temps of each thermistor from start of calibration to end of record
            BottomWaterRawData = T(1:app.NoTherm,b(end)); % temps of each thermistor right before penetration
            WaterSensorRawData = PenfileTemps(end, :); % temps of only bottom water thermistor ('T0')

            % Tapfile Records
            TapfileRecords  = PenfileRecords;
            TapfileTilt     = abs(Tilt(a));
            TapfilePressure = Depth(a);
            PenfileAveTilt  = mean(abs(Tilt(c))); % MH update for ave on-bottom tilt

            % Transpose for writing
            BottomWaterRawData = BottomWaterRawData';
            PenfileRecords     = PenfileRecords';
            PenfileTemps       = PenfileTemps';
            TapfileRecords     = TapfileRecords';
            TapfileTilt        = TapfileTilt';
            TapfilePressure    = TapfilePressure';
            WaterSensorRawData = WaterSensorRawData';

            % % OFFSET PENETRATIONRECORD, HEATPULSERECORD, BOTTOMWATERSTART, BOTTOMWATEREND
            %
            % Find closest record to penetration time
            foo = abs(PenetrationRecord_time-Time);
            a   = find(foo==min(foo));
            PenetrationRecord = record(a(1));

            % Find closest record to heat pulse time (if there was a pulse)
            if ~isempty(HeatPulseRecord_time)
                foo = abs(HeatPulseRecord_time-Time);
                a   = find(foo==min(foo));
                HeatPulseRecord = record(a(1));
            end


            % Find closest record to bottom water start time
            foo = abs(BottomWaterStart_time-Time);
            a   = find(foo==min(foo));
            % MH ROQ15 edit take next record
            BottomWaterStart = record(a(1)+1);

            % Find closest record to bottom water end time
            foo = abs(BottomWaterEnd_time-Time);
            a   = find(foo==min(foo));
            BottomWaterEnd = record(a(1));

            % Offset so records increment sequentially
            PenfileRecord_Offset = PenfileRecords(1);

            PenfileRecords_sequential = 1:1:length(PenfileRecords);
            TapfileRecords_sequential = PenfileRecords_sequential;
            a=find(PenfileRecords==PenetrationRecord);
            b=find(PenfileRecords==HeatPulseRecord);
            c=find(PenfileRecords==BottomWaterStart);
            d=find(PenfileRecords==BottomWaterEnd);
            

            PenetrationRecord_sequential = a;
            HeatPulseRecord_sequential   = b;
            BottomWaterStart_sequential  = c;
            BottomWaterEnd_sequential    = d;
            EndRecord_sequential         = PenfileRecords_sequential(end);

            BottomWaterEndData = PenfileTemps(d, 1:app.NoTherm); % this is used IF you want to define bottom water temp as the END OF CALIBRATION chosen graphically (alternate is BottomWaterRawData)

            %QC
            % set HeatPulseRecord_sequential to zero if no heat pulse
            if isempty(b)
                HeatPulseRecord_sequential = 0;
            end

            if isempty(a)||isempty(c)||isempty(d)
                keyboard % this funciton stops the execution of the file and gives control to the user's keyboard
            end

            % Get penetration number
            PenNum = str2double(extract(app.Penetration, digitsPattern));

            % Get power of the heat pulse for this penetration
            %if app.HeatPulsePens(PenNum) == 1
            %    PenPulsePower = app.PulsePower(PenNum);
            %else
            %    PenPulsePower = 0;
            %end
            
            if ~isempty(PenNum)
                PenPulsePower = num2str(app.PulsePower(PenNum));
            else
                uialert(app.figure_Main, 'Please define penetration number. Set penetration information again.', 'Error')
                return
            end

            % % Write the Pen File
            PenfileName = [penfile_name,'.pen'];
            fido = fopen(['outputs/' PenfileName],'wt');
            if fido<1
                beep;
                uialert(app.figure_Main, ['Unable to open ',PenfileName,' for writing. Check that you are in the correct directory.'], 'Error')
                return
            else
                % Header
                hdrstr1 = [app.StationName,' ',app.Penetration,' ','''', app.Cruisename,'''', ' ',app.Datum,];
                fprintf(fido,'%s\n',hdrstr1);
                fprintf(fido,'%6.6f %6.6f %6.0f %6.2f\n', str2num(app.Latitude), str2num(app.Longitude), str2num(app.Depth_mean), str2num(app.Tilt_mean));
                hdrstr3 = [app.LoggerID,'  ',app.ProbeID,'  ',app.NoActiveTherm, '  ', PenPulsePower];
                fprintf(fido,'%s\n',hdrstr3);
                fprintf(fido,'%6.0f\n',PenetrationRecord_sequential);
                fprintf(fido,'%6.0f %6.0f %6.0f\n',HeatPulseRecord_sequential,BottomWaterStart_sequential,BottomWaterEnd_sequential);
    
                % Ouptut Format for Bottom Water Data
                Fmt = '%8.3f ' ;
                FmtBW = repmat(Fmt,1,app.NoTherm);
                FmtBW = [FmtBW, '\n'];

                % Bottom Water
                %fprintf(fido,FmtBW,BottomWaterRawData); % if you want to define the bottom water temp as the final temp recorded PRIOR to penetration
                fprintf(fido,FmtBW,BottomWaterEndData); % this is used IF you want to define bottom water temp as the END OF CALIBRATION chosen graphically (alternate is BottomWaterRawData)
  
                % Output Format for Thermistor Data
                Fmt = '%8.3f ' ;
                Fmt = repmat(Fmt,1,app.NoTherm);
                Fmt = ['%6.0f ',Fmt, '\n'];
    
                % Thermistor Data + tilt + depth (m)
                [nrows,~] = size(PenfileTemps);
                i=1;
                while i<=nrows
                    fprintf(fido,Fmt,PenfileRecords_sequential(i),PenfileTemps(i,:));
                    i=i+1;
                end
                fclose(fido);
            end

            % Write the TAP file
            TapfileName = [penfile_name,'.tap'];

            fido = fopen(['outputs/' TapfileName],'wt');
            if fido<1
                beep;
                disp(['Unable to open ',TapfileName,' for writing. Breaking.'])
                return
            else
    
                % Tilt and Pressure Data
                nrows = length(TapfileRecords);
                i=1;
                while i<=nrows
                    fprintf(fido,'%6.0f %6.2f %6.0f\n',TapfileRecords_sequential(i),TapfileTilt(i),TapfilePressure(i));
                    i=i+1;
                end
                fclose(fido);
            end

            % Variables used in .pen file
            % --------------------------------------

            S_PENVAR = struct('BottomWaterRawData', BottomWaterEndData, 'AllRecords', ...
                PenfileRecords_sequential, 'AllSensorsRawData', PenfileTemps, 'WaterSensorRawData', WaterSensorRawData, ...
                'PenetrationRecord', PenetrationRecord_sequential, 'HeatPulseRecord', HeatPulseRecord_sequential, 'EndRecord', ...
                EndRecord_sequential, 'EqmStartRecord', BottomWaterStart_sequential, 'EqmEndRecord', BottomWaterEnd_sequential);

            % Create data structure to store input and output values %
            S_PenHandles = struct('CruiseName',app.Cruisename,'StationName',app.StationName,...
                'Penetration',app.Penetration,'ProbeId',app.ProbeID,'Datum',app.Datum,...
                'LoggerId',app.LoggerID,'NumberofSensors',app.NoActiveTherm,'PulsePower', PenPulsePower, 'Latitude',app.Latitude,...
                'Longitude',app.Longitude,...
                'Tilt',app.Tilt_mean,'Depth',app.Depth_mean) ;

            
            % Variables used in .ptap file
            % --------------------------------------
            S_TAPVAR = struct('TAPRecord', TapfileRecords_sequential, 'Tilt', TapfileTilt, 'Depth', TapfilePressure);


            pause(1)
            
            % Save SlugPen.mat
            save(['outputs/' penfile_name], 'S_PENVAR', 'S_PenHandles', 'S_TAPVAR');
            MatfileName = [penfile_name '.mat'];

            % Save pen, tap, and mat files in the corresponding slugheat
            % input folder
           %Pen
           cd outputs/
           filenames = {PenfileName, TapfileName, MatfileName};
           for i=1:length(filenames)
                copyfile(char(filenames(i)),'../../slugheat/inputs')
           end
           cd ..

     
           uialert(app.figure_Main, ['Output files created for penetration: ' newline ...
               penfile_name '.pen' newline ...
               penfile_name '.tap' newline ...
               penfile_name '.mat' newline...
               'and saved in in your current path outputs subfolder ' pwd '/outputs/. Files also saved in corresponding /slugheat/inputs subfolder.'], ...
               'Success', 'Icon','success')

        end

        % Button pushed function: button_SetPen
        function button_SetPenPushed(app, event)
    
         % Set input values %   
            Depth = app.DATA.Depth_dec;
            Depth = Depth';
            Time = app.DATA.Time_dec;
            Tilt = app.DATA.Tilt_dec;
            record = app.DATA.Record_dec;
            record = record';
         
         % Define penetration measurement Start and End %
            msmt_start = datenum(app.H.Selections.Start_Pen.Value, 'mm/dd/yyyy HH:MM:SS');
            msmt_end = datenum(app.H.Selections.End_Pen.Value, 'mm/dd/yyyy HH:MM:SS');

         % Find mean depth and tilt records within measurement period
               a = find(Time>=msmt_start & Time<=msmt_end);
               if ~isempty(a)
                   pen_depth = mean(Depth(a), 'omitnan');
                   pen_tilt = mean(Tilt(a), 'omitnan');
                   pen_tilt = sprintf('%2.1f', pen_tilt);
                   pen_tilt = str2double(pen_tilt);
               else
                   uialert(app.figure_Main, 'No Data in Time Window. Please assign start and end of penetration times.', 'Error', 'error')
                   return
               end
            
         % Create penfile input auxiallary app
           app.SetPenInfoApp = SetPenInfo(app, app.NoTherm, app.NumSensUsed, pen_tilt, pen_depth);

           app.export_pen.Enable = "on";
           %app.H_export_ascii.Enable = "on";
           app.PenSet = 1;

        end

        % Value changed function: edit__StartEqm
        function edit__StartEqmValueChanged(app, event)
            value = str2num(app.edit__StartEqm.Value);
            app.SE_VerticalLine = xline(app.axis_raw, value);
            app.SE_VerticalLine.Label = "Start Calibration Period (C1)";
            app.SE_VerticalLine.LabelHorizontalAlignment = 'center';
        end

        % Value changed function: checkbox_depthplot
        function checkbox_depthplotValueChanged(app, event)
            app.DepthPlot= app.checkbox_depthplot.Value;
            app.plotLayout
        end

        % Value changed function: checkbox_accelplot
        function checkbox_accelplotValueChanged(app, event)
            app.AccelPlot = app.checkbox_accelplot.Value;
            app.plotLayout
        end

        % Value changed function: checkbox_tiltplot
        function checkbox_tiltplotValueChanged(app, event)
            app.TiltPlot = app.checkbox_tiltplot.Value;
            app.plotLayout
        end

        % Button pushed function: button_quit
        function button_quitButtonPushed(app, event)
            app.grid_MainFigure.ColumnWidth = {'0x', '.01x', '1x'};

            % Align X axes of all visibile plots
            % ------------------------------------
            app.xAlign
            
        end

        % Size changed function: figure_Main
        function figure_MainSizeChanged(app, event)
           % Align inner position left edge and width (x axis) of all 4 plots
           app.xAlign
        end

        % Button pushed function: button__ResetPen
        function button__ResetPenPushed(app, event)
            delete(app.SP_VerticalLine);
            delete(app.HP_VerticalLine);
            delete(app.EP_VerticalLine);
            
            delete(app.SP_depthLine)
            delete(app.HP_depthLine)
            delete(app.EP_depthLine)
            
            delete(app.SP_accelLine)
            delete(app.HP_accelLine)
            delete(app.EP_accelLine)
            
            delete(app.SP_tiltLine)
            delete(app.HP_tiltLine)
            delete(app.EP_tiltLine)
            app.H.Selections.Value=app.dropdown_Select.Items(1);
            
            app.H.Selections.Start_Pen.Value='';
            app.H.Selections.Start_Heat.Value='';
            app.H.Selections.End_Pen.Value='';
        end

        % Button pushed function: button_CommandWindowArrow
        function button_CommandWindowArrowPushed(app, event)
            app.grid_MainFigure.ColumnWidth = {'1x', '0x', '8x'};

            % Align X axes of all visibile plots
            % ------------------------------------
            app.xAlign
        end

        % Button pushed function: button_RemoveSensors
        function button_RemoveSensorsPushed(app, event)
        % If checkbox is unchecked, make all temps for this thermistor NaN
        % -----------------------------------------------------------------
            BadSensors = [];
            for i =1:app.NoTherm
                if app.Checkboxes{i}.Value == 0
                    BadSensors(i) = i;
                end
            end
            BadSensors(BadSensors==0) = [];
  
        % Warn user before removing data
        % ------------------------------------
        confirmation = uiconfirm(app.figure_Main, ['Recordings from all unchecked ' ...
            'sensors will be discarded and REMOVED from ' ...
            'data.' newline newline ...
            'Sensors discarded ' newline int2str(BadSensors)], ...
            'Confirm sensor discard', 'Icon', 'warning');
        switch confirmation
        case 'OK'
            for i =1:app.NoTherm
                if app.Checkboxes{i}.Value == 0
                    app.DATA.Tdec(i,:) = zeros(size(app.DATA.Tdec(i,:)))/0;
                    app.DATA.Tcln(i,:) = zeros(size(app.DATA.Tcln(i,:)))/0;
                    app.DATA.Traw(i,:) = zeros(size(app.DATA.Traw(i,:)))/0;
                    app.Checkboxes{i}.Enable = 0;
                end
            end
            
        end

        app.NumSensUsed = app.NoTherm - length(BadSensors); % number of sensors NOT removed, including bottom water
        end

        % Value changed function: checkbox_UseHP
        function checkbox_UseHPValueChanged(app, event)
            Pulse = app.checkbox_UseHP.Value;
            
            if Pulse == 1
                app.PulseData = 1;
            elseif Pulse == 0
                app.PulseData = 0;
            end

        end

        % Value changed function: edit_EndEqm
        function edit_EndEqmValueChanged(app, event)
            value = app.edit_EndEqm.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure_Main = uifigure('Visible', 'off');
            app.figure_Main.Name = 'SlugPen';
            app.figure_Main.SizeChangedFcn = createCallbackFcn(app, @figure_MainSizeChanged, true);
            app.grid_MainFigure = uigridlayout(app.figure_Main);
            app.panel_Data = uipanel(app.grid_MainFigure);
            app.grid_data = uigridlayout(app.panel_Data);
            app.panel_create = uipanel(app.grid_data);
            app.panel_create.Title = 'Output Controls';
            app.grid_create = uigridlayout(app.panel_create);
            app.export_pen = uibutton(app.grid_create, 'push');
            app.export_pen.ButtonPushedFcn = createCallbackFcn(app, @export_penButtonPushed, true);
            app.export_pen.Text = 'Create PEN file';
            app.panel_SlugPen = uipanel(app.grid_data);
            app.grid_SlugPen = uigridlayout(app.panel_SlugPen);
            app.button_quit = uibutton(app.grid_SlugPen, 'push');
            app.button_quit.ButtonPushedFcn = createCallbackFcn(app, @button_quitButtonPushed, true);
            app.button_quit.Icon = fullfile(pathToMLAPP, 'Images', 'x.png');
            app.button_quit.Text = '';
            app.button_restart = uibutton(app.grid_SlugPen, 'push');
            app.button_restart.ButtonPushedFcn = createCallbackFcn(app, @button_restartButtonPushed, true);
            app.button_restart.Text = 'Restart';
            app.image_SlugPen = uiimage(app.grid_SlugPen);
            app.image_SlugPen.ImageSource = fullfile(pathToMLAPP, 'Images', 'SlugPenLogo.png');
            app.image_UCSC = uiimage(app.grid_SlugPen);
            app.image_UCSC.ImageSource = fullfile(pathToMLAPP, 'Images', 'UCSC-HydrogeologyLogo.jpg');
            app.panel_plotcontrols = uipanel(app.grid_data);
            app.panel_plotcontrols.Title = 'Plot Controls';
            app.grid_plotcontrols = uigridlayout(app.panel_plotcontrols);
            app.label_plotscheckboxes = uilabel(app.grid_plotcontrols);
            app.label_plotscheckboxes.Text = 'Plots:';
            app.label_datacheckboxes = uilabel(app.grid_plotcontrols);
            app.label_datacheckboxes.Text = 'Data:';
            app.checkbox_depthplot = uicheckbox(app.grid_plotcontrols);
            app.checkbox_depthplot.ValueChangedFcn = createCallbackFcn(app, @checkbox_depthplotValueChanged, true);
            app.checkbox_depthplot.Text = 'Depth';
            app.checkbox_rawp = uicheckbox(app.grid_plotcontrols);
            app.checkbox_rawp.ValueChangedFcn = createCallbackFcn(app, @checkbox_rawpValueChanged, true);
            app.checkbox_rawp.Text = 'Raw';
            app.checkbox_rawp.Value = true;
            app.checkbox_accelplot = uicheckbox(app.grid_plotcontrols);
            app.checkbox_accelplot.ValueChangedFcn = createCallbackFcn(app, @checkbox_accelplotValueChanged, true);
            app.checkbox_accelplot.Text = 'Acceleration';
            app.checkbox_clnp = uicheckbox(app.grid_plotcontrols);
            app.checkbox_clnp.ValueChangedFcn = createCallbackFcn(app, @checkbox_clnpValueChanged, true);
            app.checkbox_clnp.Text = 'Clean';
            app.checkbox_clnp.Value = true;
            app.checkbox_tiltplot = uicheckbox(app.grid_plotcontrols);
            app.checkbox_tiltplot.ValueChangedFcn = createCallbackFcn(app, @checkbox_tiltplotValueChanged, true);
            app.checkbox_tiltplot.Text = 'Tilt';
            app.checkbox_decp = uicheckbox(app.grid_plotcontrols);
            app.checkbox_decp.ValueChangedFcn = createCallbackFcn(app, @checkbox_decpValueChanged, true);
            app.checkbox_decp.Text = 'Decimate';
            app.checkbox_decp.Value = true;
            app.panel_selected = uipanel(app.grid_data);
            app.panel_selected.Title = 'Event Selections ';
            app.grid_selected = uigridlayout(app.panel_selected);
            app.dropdown_Select = uidropdown(app.grid_selected);
            app.dropdown_Select.Items = {'Start Calibration Period (C1)', 'End Calibration Period (C2)', 'Start Penetration (P)', 'End Penetration (E)', 'Heat Pulse (H)'};
            app.dropdown_Select.ValueChangedFcn = createCallbackFcn(app, @dropdown_SelectValueChanged, true);
            app.dropdown_Select.Tooltip = {'Click to graphically select data for creating penfile'};
            app.dropdown_Select.Value = 'Start Calibration Period (C1)';
            app.button_ResetSelection = uibutton(app.grid_selected, 'push');
            app.button_ResetSelection.ButtonPushedFcn = createCallbackFcn(app, @button_ResetSelectionPushed, true);
            app.button_ResetSelection.Text = 'Reset All';
            app.button_BeginSelections = uibutton(app.grid_selected, 'push');
            app.button_BeginSelections.ButtonPushedFcn = createCallbackFcn(app, @button_BeginSelectionsButtonPushed, true);
            app.button_BeginSelections.Text = 'Select Event';
            app.button_SetPen = uibutton(app.grid_selected, 'push');
            app.button_SetPen.ButtonPushedFcn = createCallbackFcn(app, @button_SetPenPushed, true);
            app.button_SetPen.Text = 'Set Penetration';
            app.button__ResetPen = uibutton(app.grid_selected, 'push');
            app.button__ResetPen.ButtonPushedFcn = createCallbackFcn(app, @button__ResetPenPushed, true);
            app.button__ResetPen.Text = 'Reset Penetration';
            app.checkbox_UseHP = uicheckbox(app.grid_selected);
            app.checkbox_UseHP.ValueChangedFcn = createCallbackFcn(app, @checkbox_UseHPValueChanged, true);
            app.checkbox_UseHP.Text = 'Use heat pulse ';
            app.checkbox_UseHP.Value = true;
            app.StartCalibrationPeriodW1Label = uilabel(app.grid_selected);
            app.StartCalibrationPeriodW1Label.Text = 'Start Cal. Period (W1): ';
            app.edit__StartEqm = uieditfield(app.grid_selected, 'text');
            app.edit__StartEqm.CharacterLimits = [0 20];
            app.edit__StartEqm.ValueChangedFcn = createCallbackFcn(app, @edit__StartEqmValueChanged, true);
            app.edit__StartEqm.Editable = 'off';
            app.EndCalibrationPeriodW2Label = uilabel(app.grid_selected);
            app.EndCalibrationPeriodW2Label.Text = 'End Cal. Period (W2): ';
            app.edit_EndEqm = uieditfield(app.grid_selected, 'text');
            app.edit_EndEqm.CharacterLimits = [0 20];
            app.edit_EndEqm.ValueChangedFcn = createCallbackFcn(app, @edit_EndEqmValueChanged, true);
            app.edit_EndEqm.Editable = 'off';
            app.StartPenetrationPEditFieldLabel = uilabel(app.grid_selected);
            app.StartPenetrationPEditFieldLabel.Text = 'Start Penetration (P): ';
            app.edit_PenStart = uieditfield(app.grid_selected, 'text');
            app.edit_PenStart.CharacterLimits = [0 20];
            app.edit_PenStart.Editable = 'off';
            app.EndPenetrationEEditFieldLabel = uilabel(app.grid_selected);
            app.EndPenetrationEEditFieldLabel.Text = 'End Penetration (E): ';
            app.edit_End = uieditfield(app.grid_selected, 'text');
            app.edit_End.CharacterLimits = [0 20];
            app.edit_End.Editable = 'off';
            app.HeatPulseHEditFieldLabel = uilabel(app.grid_selected);
            app.HeatPulseHEditFieldLabel.Text = 'Heat Pulse (H): ';
            app.edit_HeatPulse = uieditfield(app.grid_selected, 'text');
            app.edit_HeatPulse.CharacterLimits = [0 20];
            app.edit_HeatPulse.Editable = 'off';
            app.panel_info = uipanel(app.grid_data);
            app.panel_info.Title = 'Raw Data';
            app.grid_datainfo = uigridlayout(app.panel_info);
            app.FilenameLabel = uilabel(app.grid_datainfo);
            app.FilenameLabel.Text = 'Filename: ';
            app.edit_filename = uieditfield(app.grid_datainfo, 'text');
            app.StartDateEditFieldLabel = uilabel(app.grid_datainfo);
            app.StartDateEditFieldLabel.Text = 'Start Date:';
            app.edit_start_date = uieditfield(app.grid_datainfo, 'text');
            app.StartTimeEditFieldLabel = uilabel(app.grid_datainfo);
            app.StartTimeEditFieldLabel.Text = 'Start Time:';
            app.edit_start_time = uieditfield(app.grid_datainfo, 'text');
            app.EndTimeEditFieldLabel = uilabel(app.grid_datainfo);
            app.EndTimeEditFieldLabel.Text = 'End Time:';
            app.edit_end_time = uieditfield(app.grid_datainfo, 'text');
            app.LogIntervalsLabel = uilabel(app.grid_datainfo);
            app.LogIntervalsLabel.Text = 'Log Interval (s):';
            app.edit_logint = uieditfield(app.grid_datainfo, 'text');
            app.PulsesecEditFieldLabel = uilabel(app.grid_datainfo);
            app.PulsesecEditFieldLabel.Text = 'Pulse (sec): ';
            app.edit_pulse_s = uieditfield(app.grid_datainfo, 'text');
            app.DecayminEditFieldLabel = uilabel(app.grid_datainfo);
            app.DecayminEditFieldLabel.Text = 'Decay (min):';
            app.edit_decay = uieditfield(app.grid_datainfo, 'text');
            app.PulseWLabel = uilabel(app.grid_datainfo);
            app.PulseWLabel.Text = 'Pulse (W): ';
            app.edit_pulse_W = uieditfield(app.grid_datainfo, 'text');
            app.edit_load = uibutton(app.grid_datainfo, 'push');
            app.edit_load.ButtonPushedFcn = createCallbackFcn(app, @edit_loadPushed, true);
            app.edit_load.Text = 'Load Raw Data';
            app.EditField_3 = uieditfield(app.grid_datainfo, 'numeric');
            app.EditField_3.Visible = 'off';
            app.EditField_2 = uieditfield(app.grid_datainfo, 'numeric');
            app.EditField_2.Visible = 'off';
            app.NumberofThermistorsLabel = uilabel(app.grid_datainfo);
            app.NumberofThermistorsLabel.Text = 'Number of Thermistors:';
            app.edit_NumberofTherm = uieditfield(app.grid_datainfo, 'text');
            app.label_FilterWinfowLength = uilabel(app.grid_datainfo);
            app.label_FilterWinfowLength.Enable = 'off';
            app.label_FilterWinfowLength.Visible = 'off';
            app.label_FilterWinfowLength.Text = 'Filter Window Lengths:';
            app.MedianEditFieldLabel = uilabel(app.grid_datainfo);
            app.MedianEditFieldLabel.Enable = 'off';
            app.MedianEditFieldLabel.Visible = 'off';
            app.MedianEditFieldLabel.Text = 'Median:';
            app.edit_MedianFilter = uieditfield(app.grid_datainfo, 'numeric');
            app.edit_MedianFilter.Enable = 'off';
            app.edit_MedianFilter.Visible = 'off';
            app.edit_MedianFilter.Value = 2;
            app.MeanEditFieldLabel = uilabel(app.grid_datainfo);
            app.MeanEditFieldLabel.Enable = 'off';
            app.MeanEditFieldLabel.Visible = 'off';
            app.MeanEditFieldLabel.Text = 'Mean: ';
            app.edit_MeanFilter = uieditfield(app.grid_datainfo, 'numeric');
            app.edit_MeanFilter.Enable = 'off';
            app.edit_MeanFilter.Visible = 'off';
            app.edit_MeanFilter.Value = 6;
            app.DecimateEditFieldLabel = uilabel(app.grid_datainfo);
            app.DecimateEditFieldLabel.Enable = 'off';
            app.DecimateEditFieldLabel.Visible = 'off';
            app.DecimateEditFieldLabel.Text = 'Decimate:';
            app.edit_DecimateFilter = uieditfield(app.grid_datainfo, 'numeric');
            app.edit_DecimateFilter.Enable = 'off';
            app.edit_DecimateFilter.Visible = 'off';
            app.edit_DecimateFilter.Value = 10;
            app.panel_PlotWindow = uipanel(app.grid_MainFigure);
            app.grid_PlotWindow = uigridlayout(app.panel_PlotWindow);
            app.axis_raw = uiaxes(app.grid_PlotWindow);
            title(app.axis_raw, 'Time Series')
            ylabel(app.axis_raw, 'Temperature (°C)')
            app.axis_depth = uiaxes(app.grid_PlotWindow);
            ylabel(app.axis_depth, 'Depth (m)')
            app.axis_depth.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.axis_accel = uiaxes(app.grid_PlotWindow);
            ylabel(app.axis_accel, 'Acceleration (g)')
            app.axis_tilt = uiaxes(app.grid_PlotWindow);
            ylabel(app.axis_tilt, 'Tilt (°)')
            app.label_Time = uilabel(app.grid_PlotWindow);
            app.label_Time.Text = 'Time (hr:min)';
            app.panel_TopLabels = uipanel(app.grid_PlotWindow);
            app.grid_TopLabels = uigridlayout(app.panel_TopLabels);
            app.label_SlugHeat = uilabel(app.grid_TopLabels);
            app.label_SlugHeat.Text = 'SlugPen';
            app.label_CurrentPath = uilabel(app.grid_TopLabels);
            app.label_CurrentPath.Text = 'Current Path:';
            app.label_currentpathfull = uilabel(app.grid_TopLabels);
            app.label_currentpathfull.Text = '';
            app.panel_Top = uipanel(app.grid_PlotWindow);
            app.panel_ProcessingInfoLabels = uipanel(app.grid_PlotWindow);
            app.grid_ProcessingInfoLabels = uigridlayout(app.panel_ProcessingInfoLabels);
            app.edit_StationTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_StationTitle.Text = 'Station: ';
            app.edit_PenetrationTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_PenetrationTitle.Text = 'Penetration:';
            app.edit_LatitudeTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_LatitudeTitle.Text = 'Latitude:';
            app.edit_LongitudeTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_LongitudeTitle.Text = 'Longitude:';
            app.edit_ProcessingTimeTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_ProcessingTimeTitle.Text = 'Processing Time:';
            app.edit_CruiseTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_CruiseTitle.Text = 'Cuise:';
            app.label_Cruise = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Cruise.Text = 'cruise name';
            app.label_Station = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Station.Text = 'station name';
            app.label_Penetration = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Penetration.Text = 'penetration name';
            app.label_Latitude = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Latitude.Text = 'latitude';
            app.label_Longitude = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Longitude.Text = 'longitude';
            app.label_ProcessingTime = uilabel(app.grid_ProcessingInfoLabels);
            app.label_ProcessingTime.Text = 'start of processing';
            app.panel_Thermistors = uipanel(app.grid_PlotWindow);
            app.panel_Thermistors.Title = 'Thermistors';
            app.button_RemoveSensors = uibutton(app.grid_PlotWindow, 'push');
            app.button_RemoveSensors.ButtonPushedFcn = createCallbackFcn(app, @button_RemoveSensorsPushed, true);
            app.button_RemoveSensors.Text = 'Remove Sensors';
            app.button_CommandWindowArrow = uibutton(app.grid_MainFigure, 'push');
            app.button_CommandWindowArrow.ButtonPushedFcn = createCallbackFcn(app, @button_CommandWindowArrowPushed, true);
            app.button_CommandWindowArrow.Icon = fullfile(pathToMLAPP, 'Images', 'arrowpen.png');
            app.button_CommandWindowArrow.Text = '';
            % Show the figure after all components are created
            app.figure_Main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SlugPen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure_Main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_Main)
        end
    end
end